classdef model_option_App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SUBMODELSTab                    matlab.ui.container.Tab
        SubmodelsPanel                  matlab.ui.container.Panel
        CropGrowthProcessCheckBox       matlab.ui.control.CheckBox
        TurbulenceCheckBox              matlab.ui.control.CheckBox
        HydraulicRedistributionHRCheckBox  matlab.ui.control.CheckBox
        SoilHeatModelCheckBox           matlab.ui.control.CheckBox
        SoilNutrientModelCheckBox       matlab.ui.control.CheckBox
        DenitrificationCheckBox         matlab.ui.control.CheckBox
        AtmosphericDepositionCheckBox   matlab.ui.control.CheckBox
        gNm2yrLabel                     matlab.ui.control.Label
        AmmoniumLabel                   matlab.ui.control.Label
        AmmoniumEditField               matlab.ui.control.NumericEditField
        NitrateLabel                    matlab.ui.control.Label
        NitrateEditField                matlab.ui.control.NumericEditField
        NitrogenAllocationModelCheckBox  matlab.ui.control.CheckBox
        ParrallelComputingSwitchLabel   matlab.ui.control.Label
        ParrallelComputingSwitch        matlab.ui.control.Switch
        text4                           matlab.ui.control.Label
        CANOPYPROCESSTab                matlab.ui.container.Tab
        SpeciesButtonGroup              matlab.ui.container.ButtonGroup
        SinglespeciessimulationButton   matlab.ui.control.RadioButton
        MultispeciesButton              matlab.ui.control.RadioButton
        NumbersEditField                matlab.ui.control.NumericEditField
        WaterRetentionCurveButtonGroup  matlab.ui.container.ButtonGroup
        vanGenButton                    matlab.ui.control.RadioButton
        BrooksButton                    matlab.ui.control.RadioButton
        RootHydraulicConductivityButtonGroup  matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        NochangeButton                  matlab.ui.control.RadioButton
        CO2ConcentrationPPMButtonGroup  matlab.ui.container.ButtonGroup
        AmbientButton                   matlab.ui.control.RadioButton
        NumbersEditField_2              matlab.ui.control.NumericEditField
        ElevatedButton                  matlab.ui.control.RadioButton
        NumbersEditField_3              matlab.ui.control.NumericEditField
        TemperatureChangeCButtonGroup   matlab.ui.container.ButtonGroup
        AmbientButton_2                 matlab.ui.control.RadioButton
        ElevatedButton_2                matlab.ui.control.RadioButton
        NumbersEditField_4              matlab.ui.control.NumericEditField
        text5                           matlab.ui.control.Label
        YEARWISEDETAILSTab              matlab.ui.container.Tab
        StartYearEditFieldLabel         matlab.ui.control.Label
        StartYearEditField              matlab.ui.control.NumericEditField
        EndYearEditFieldLabel           matlab.ui.control.Label
        EndYearEditField                matlab.ui.control.NumericEditField
        FillYearsButton                 matlab.ui.control.Button
        SelectYearsLabel                matlab.ui.control.Label
        YearsList                       matlab.ui.control.DropDown
        SpeciesDropDownLabel            matlab.ui.control.Label
        SpeciesDropDown                 matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        SavedLamp                       matlab.ui.control.Lamp
        GrDetails                       matlab.ui.container.TabGroup
        VegetationDetailsTab            matlab.ui.container.Tab
        VegetationSwitchLabel           matlab.ui.control.Label
        VegetationSwitch                matlab.ui.control.Switch
        Photosynthesis                  matlab.ui.container.ButtonGroup
        PhotosynthesisLabel             matlab.ui.control.Label
        C3Button                        matlab.ui.control.RadioButton
        C4Button                        matlab.ui.control.RadioButton
        VegetationNameEditFieldLabel    matlab.ui.control.Label
        VegetationNameEditField         matlab.ui.control.EditField
        CanopyStructurePanel            matlab.ui.container.Panel
        CanopyLayersEditFieldLabel      matlab.ui.control.Label
        CanopyLayersEditField           matlab.ui.control.NumericEditField
        AddEditButton                   matlab.ui.control.Button
        Load                            matlab.ui.control.Button
        RootStructurePanel              matlab.ui.container.Panel
        RootLayersEditFieldLabel        matlab.ui.control.Label
        RootLayersEditField             matlab.ui.control.NumericEditField
        AddEditButton_2                 matlab.ui.control.Button
        Load_2                          matlab.ui.control.Button
        Plot_CStructure                 matlab.ui.control.UIAxes
        Plot_RStructure                 matlab.ui.control.UIAxes
        ManagementPracticesTab          matlab.ui.container.Tab
        PlantingDOYEditFieldLabel       matlab.ui.control.Label
        PlantingDOYEditField            matlab.ui.control.NumericEditField
        HarvestDOYEditFieldLabel        matlab.ui.control.Label
        HarvestDOYEditField             matlab.ui.control.NumericEditField
        IrrigatedCheckBox               matlab.ui.control.CheckBox
        MethodofIrrigationListBox       matlab.ui.control.DropDown
        FertilizersAppliedCheckBox      matlab.ui.control.CheckBox
        MethodofFertilizersListBox      matlab.ui.control.DropDown
        AddIrrigationButton             matlab.ui.control.Button
        DelIrrigationButton             matlab.ui.control.Button
        IrrigationschedulesLabel        matlab.ui.control.Label
        IrrTable                        matlab.ui.control.Table
        AddFertilizerButton             matlab.ui.control.Button
        DelFertilizerButton             matlab.ui.control.Button
        NitrogenFertilizersschedulesLabel  matlab.ui.control.Label
        NitTable                        matlab.ui.control.Table
        SOILCARBONANDNITROGENPROCESSTab  matlab.ui.container.Tab
        SoilOrganicCarbonButtonGroup    matlab.ui.container.ButtonGroup
        PoolButton                      matlab.ui.control.RadioButton
        PoolsButton                     matlab.ui.control.RadioButton
        NUptakeButtonGroup              matlab.ui.container.ButtonGroup
        RB_table                        matlab.ui.control.Table
        PlantNdemandButton              matlab.ui.control.RadioButton
        NitrogenFixationCheckBox        matlab.ui.control.CheckBox
        NitrogenRemobilizationCheckBox  matlab.ui.control.CheckBox
        RootBiomassButton               matlab.ui.control.RadioButton
        text6                           matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        selcI;
        selcN;
        dat_LADA;
        dat_rootA;
        num_LADA;
        num_rootA;
        litter_depthA;
        opt_rootA;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% Sub Models
            
            load('.\Temps\temp_model.mat', "CGM", "Turbulence",...
                "HR", "Soil_heat", "Soil_nutrient", "N_denit", ...
                "N_Adepo", "N_Adepo_amm", "N_Adepo_nit", "NCanAlMod", 'ParOn');
            
            app.CropGrowthProcessCheckBox.Value=CGM;
            app.TurbulenceCheckBox.Value=Turbulence;
            app.HydraulicRedistributionHRCheckBox.Value=HR;
            app.SoilHeatModelCheckBox.Value=Soil_heat;
            app.SoilNutrientModelCheckBox.Value=Soil_nutrient;
            
            app.DenitrificationCheckBox.Value=N_denit;
            app.AtmosphericDepositionCheckBox.Value=N_Adepo;
            app.AmmoniumEditField.Value=N_Adepo_amm;
            app.NitrateEditField.Value=N_Adepo_nit;
            app.NitrogenAllocationModelCheckBox.Value=NCanAlMod;
            app.ParrallelComputingSwitch.Value=ParOn;
            
            if Soil_nutrient==1
                
                app.DenitrificationCheckBox.Enable='on';
                app.AtmosphericDepositionCheckBox.Enable='on';
                app.gNm2yrLabel.Enable='on';
                
                if  N_Adepo==1
                    app.AmmoniumEditField.Enable='on';
                    app.NitrateEditField.Enable='on';
                    app.AmmoniumLabel.Enable='on';
                    app.NitrateLabel.Enable='on';
                else
                    app.AmmoniumEditField.Enable='off';
                    app.NitrateEditField.Enable='off';
                    app.AmmoniumLabel.Enable='off';
                    app.NitrateLabel.Enable='off';
                end
                
                app.NitrogenAllocationModelCheckBox.Enable='on';
                
                app.ParrallelComputingSwitch.Enable='on';
                
                app.SOILCARBONANDNITROGENPROCESSTab.Parent=app.TabGroup;
                
            else
                
                app.DenitrificationCheckBox.Enable='off';
                app.AtmosphericDepositionCheckBox.Enable='off';
                app.gNm2yrLabel.Enable='off';
                
                app.AmmoniumEditField.Enable='off';
                app.NitrateEditField.Enable='off';
                app.AmmoniumLabel.Enable='off';
                app.NitrateLabel.Enable='off';
                
                app.NitrogenAllocationModelCheckBox.Enable='off';
                
                app.ParrallelComputingSwitch.Enable='off';
                
                app.SOILCARBONANDNITROGENPROCESSTab.Parent=[];
                
            end
            
            %% Soil CN Process
            
            load('.\Temps\temp_model.mat','Soil_C_pool1',...
                'N_Uptake_RB', 'opt_root_para', 'N_Fix', 'N_Remo')
            
            app.PoolButton.Value=Soil_C_pool1;
            
            app.RootBiomassButton.Value=N_Uptake_RB;
            
            app.RB_table.Data=opt_root_para;
            
            if N_Uptake_RB==1
                app.RB_table.Enable='on';
                app.NitrogenFixationCheckBox.Enable='off';
                app.NitrogenRemobilizationCheckBox.Enable='off';
            else
                app.RB_table.Enable='off';
                app.NitrogenFixationCheckBox.Enable='on';
                app.NitrogenRemobilizationCheckBox.Enable='on';
            end
            
            app.NitrogenFixationCheckBox.Value=N_Fix;
            app.NitrogenRemobilizationCheckBox.Value=N_Remo;
            
            
            %% Canopy Process
            
            load('.\Temps\temp_model.mat', 'Sim_species',...
                'Sim_species_con', 'num_species', 'vanGen', 'RHC',...
                'CO2_Ambient', 'CO2_Elev', 'CO2_Elev_con',...
                'Temp_Elev', 'Temp_Elev_con')
            
            app.SinglespeciessimulationButton.Value=Sim_species;
            
            if(Sim_species==1)
                num_species=1;
                Sim_species_con=1;
            end
            
            app.NumbersEditField.Value=Sim_species_con;
            
            if Sim_species==1
                app.NumbersEditField.Enable='off';
            else
                app.NumbersEditField.Enable='on';
            end
            
            app.vanGenButton.Value=vanGen;
            app.LinearButton.Value=RHC;
            
            
            app.NumbersEditField_2.Value=CO2_Ambient;
            app.NumbersEditField_2.Enable='off';
            
            app.ElevatedButton.Value=CO2_Elev;
            app.NumbersEditField_3.Value=CO2_Elev_con;
            
            if CO2_Elev==1
                app.NumbersEditField_3.Enable='on';
            else
                app.NumbersEditField_3.Enable='off';
            end
            
            app.ElevatedButton_2.Value=Temp_Elev;
            app.NumbersEditField_4.Value=Temp_Elev_con;
            
            if Temp_Elev==1
                app.NumbersEditField_4.Enable='on';
            else
                app.NumbersEditField_4.Enable='off';
            end
            
            
            load ('.\Temps\temp_model.mat', "Start_Y", "End_Y")
            
            app.StartYearEditField.Value=Start_Y;
            app.EndYearEditField.Value=End_Y;
            
            app.YearsList.Enable='off';
            app.SpeciesDropDown.Enable='off';
            app.GrDetails.Visible='off';
            app.GrDetails.SelectedTab=app.GrDetails.Children(1);
            app.SaveButton.Visible='off';
            
            
        end

        % Cell edit callback: RB_table
        function RB_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Display data changed function: RB_table
        function RB_tableDisplayDataChanged(app, event)
            newDisplayData = app.RB_table.DisplayData;
            
        end

        % Cell selection callback: RB_table
        function RB_tableCellSelection(app, event)
            indices = event.Indices;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            %Sub-models
            CGM             = app.CropGrowthProcessCheckBox.Value;
            Turbulence      = app.TurbulenceCheckBox.Value;
            HR              = app.HydraulicRedistributionHRCheckBox.Value;
            Soil_heat       = app.SoilHeatModelCheckBox.Value;
            Soil_nutrient   = app.SoilNutrientModelCheckBox.Value;
            NCanAlMod       = app.NitrogenAllocationModelCheckBox.Value;
            ParOn           = app.ParrallelComputingSwitch.Value;
            N_denit         = app.DenitrificationCheckBox.Value;
            N_Adepo         = app.AtmosphericDepositionCheckBox.Value;
            N_Adepo_amm     = app.AmmoniumEditField.Value;
            N_Adepo_nit     = app.NitrateEditField.Value;
            
            %Canopy Process
            Sim_species     = app.SinglespeciessimulationButton.Value;
            %             Multi_species   = app.MultispeciesButton.Value;
            
            
            vanGen          = app.vanGenButton.Value;
            RHC             = app.LinearButton.Value;
            
            CO2_Ambient     = app.NumbersEditField_2.Value;
            %             CO2_Amb_con    = app.NumbersEditField_2.Value;
            CO2_Elev        = app.ElevatedButton.Value;
            CO2_Elev_con    = app.NumbersEditField_3.Value;
            
            Temp_Elev       = app.ElevatedButton_2.Value;
            Temp_Elev_con   = app.NumbersEditField_4.Value;
            
            % Yearwise Lists:
            Start_Y=app.StartYearEditField.Value;
            End_Y=app.EndYearEditField.Value;
            
            if(app.SinglespeciessimulationButton.Value==1)
                num_species=1;
                Sim_species_con=1;
            else
                num_species=app.NumbersEditField.Value;
                Sim_species_con = app.NumbersEditField.Value;
            end
            
            
            RV=Start_Y:End_Y;
            
            for ii=1:num_species
                for jj=1:length(RV)
                    RVi=RV(jj);
                    file_name   = sprintf('YrwdSpec_%sY%s.mat', num2str(ii), num2str(RVi));
                    
                    load('.\Temps\temporary.mat', 'config_path');
                    
                    YrPath=fullfile(config_path, 'YearFr');
                    
                    
                    fullpath    =...
                        fullfile(YrPath, file_name);
                    
                    YrFilesList{ii,jj}=fullpath;
                                        
                    if ~isfile(fullpath)
                        msgbox ('Create all yearwise files','MLCan Error','error');
                        return
                    end
                    
                end
                
                filename_P= sprintf('ParSpec_%s.mat', ii);
                
                load('.\Temps\temporary.mat', 'config_path');
                
                PrPath=fullfile(config_path, 'Par');
                
                fullpath_P= fullfile(PrPath, filename_P);
                
                ParFilesList{ii}=fullpath_P;
                
            end
            
            
            % Soil CN process
            Soil_C_pool1    = app.PoolButton.Value;      % 1 = 1 pool, 0 = 2 pool
            opt_root_para   = app.RB_table.Data;
            N_Uptake_RB     = app.RootBiomassButton.Value;   % 1 = Juan, 0 = Dongkook
            N_Fix           = app.NitrogenFixationCheckBox.Value;
            N_Remo          = app.NitrogenRemobilizationCheckBox.Value;
            
            
            save ('./Temps/temp_model.mat',...
                ... %Sub models
                'CGM', 'Turbulence','HR', 'Soil_heat', 'Soil_nutrient',...
                'NCanAlMod', 'ParOn', 'N_denit', 'N_Adepo', 'N_Adepo_amm',...
                'N_Adepo_nit', ...
                ... % Canopy Process
                'num_species', 'Sim_species', 'Sim_species_con', 'vanGen', ...
                'RHC', 'CO2_Ambient', 'CO2_Elev',...
                'CO2_Elev_con', 'Temp_Elev', 'Temp_Elev_con',...
                ... % Soil CN Process
                'Soil_C_pool1', 'N_Remo', 'N_Fix', 'N_Uptake_RB', ...
                'N_Fix', 'N_Remo', 'opt_root_para', ...
                ... % YearList
                'YrFilesList', 'ParFilesList',...
                '-append')
            
            load ('./Temps/temporary.mat',...
                'working_name'  )
            copyfile('./Temps/temp_model.mat', working_name, 'f');
            closereq
        end

        % Value changed function: CropGrowthProcessCheckBox
        function CropGrowthProcessCheckBoxValueChanged(app, event)
            CGM = app.CropGrowthProcessCheckBox.Value;
        end

        % Value changed function: SoilNutrientModelCheckBox
        function SoilNutrientModelCheckBoxValueChanged(app, event)
            
            if app.SoilNutrientModelCheckBox.Value==0
                app.DenitrificationCheckBox.Enable='off';
                app.AtmosphericDepositionCheckBox.Enable='off';
                app.NitrogenAllocationModelCheckBox.Enable='off';
                app.ParrallelComputingSwitch.Enable='off';
                app.gNm2yrLabel.Enable='off';
                app.SOILCARBONANDNITROGENPROCESSTab.Parent=[];
                
                app.AtmosphericDepositionCheckBox.Value=0;
                
                app.AmmoniumEditField.Enable='off';
                app.NitrateEditField.Enable='off';
                app.AmmoniumLabel.Enable='off';
                app.NitrateLabel.Enable='off';
                
            else
                app.DenitrificationCheckBox.Enable='on';
                app.AtmosphericDepositionCheckBox.Enable='on';
                app.NitrogenAllocationModelCheckBox.Enable='on';
                app.ParrallelComputingSwitch.Enable='on';
                app.gNm2yrLabel.Enable='on';
                app.SOILCARBONANDNITROGENPROCESSTab.Parent=app.TabGroup;
                app.AtmosphericDepositionCheckBox.Value=0;
                
            end
            
        end

        % Selection changed function: SpeciesButtonGroup
        function SpeciesButtonGroupSelectionChanged(app, event)
            
            if app.MultispeciesButton.Value==1
                app.NumbersEditField.Enable='on';
            else
                app.NumbersEditField.Enable='off';
            end
            
        end

        % Selection changed function: TemperatureChangeCButtonGroup
        function TemperatureChangeCButtonGroupSelectionChanged(app, event)
            
            if app.ElevatedButton_2.Value==1
                app.NumbersEditField_4.Enable='on';
            else
                app.NumbersEditField_4.Enable='off';
            end
            
        end

        % Selection changed function: CO2ConcentrationPPMButtonGroup
        function CO2ConcentrationPPMButtonGroupSelectionChanged(app, event)
            
            if app.ElevatedButton.Value==1
                app.NumbersEditField_3.Enable='on';
                app.NumbersEditField_2.Enable='off';
            else
                app.NumbersEditField_3.Enable='off';
                app.NumbersEditField_2.Enable='on';
            end
        end

        % Selection changed function: NUptakeButtonGroup
        function NUptakeButtonGroupSelectionChanged(app, event)
            
            if app.RootBiomassButton.Value==1
                app.RB_table.Enable='on';
                app.NitrogenFixationCheckBox.Enable='off';
                app.NitrogenRemobilizationCheckBox.Enable='off';
            else
                app.RB_table.Enable='off';
                app.NitrogenFixationCheckBox.Enable='on';
                app.NitrogenRemobilizationCheckBox.Enable='on';
            end
            
        end

        % Value changed function: AtmosphericDepositionCheckBox
        function AtmosphericDepositionCheckBoxValueChanged(app, event)
            
            if app.AtmosphericDepositionCheckBox.Value==1
                app.AmmoniumEditField.Enable='on';
                app.NitrateEditField.Enable='on';
                app.AmmoniumLabel.Enable='on';
                app.NitrateLabel.Enable='on';
            else
                app.AmmoniumEditField.Enable='off';
                app.NitrateEditField.Enable='off';
                app.AmmoniumLabel.Enable='off';
                app.NitrateLabel.Enable='off';
            end
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            
        end

        % Button pushed function: FillYearsButton
        function FillYearsButtonPushed(app, event)
            
            Start_Y=app.StartYearEditField.Value;
            End_Y=app.EndYearEditField.Value;
            
            save('.\Temps\temp_model.mat', 'Start_Y', 'End_Y',...
                '-append')
            
            RY=Start_Y:End_Y;
            RYS=compose('%g', RY);
            app.YearsList.Items = ['Select Year', RYS];
            app.YearsList.Enable='on';
            
            app.YearsList.Value='Select Year';
            
            app.GrDetails.Visible='off';
            
            app.SaveButton.Visible='off';
            
            load('.\Temps\temporary.mat', 'config_path');
                        
            Config_folder=config_path;
            
            num_species=app.NumbersEditField.Value;
            
            if strcmpi(num_species, '')
                num_species=1;
            end
            
            if(app.SinglespeciessimulationButton.Value==1)
                num_species=1;
            end
            
            Species = compose('%g', 1:num_species);
            
            app.SpeciesDropDown.Items=Species;
            
            app.SpeciesDropDown.Enable='on';
            
            
            Vi=size(dir([Config_folder, '/YearFr/*.mat']));
            Vs=Vi(1);
            
            if Vs>=1
                answer = questdlg('Do you want to delete old yearwise files from the folder and create new files?', ...
                    'Create new files', ...
                    'No','Yes', 'No');
                switch answer
                    case 'Yes'
                        delete([Config_folder, '/YearFr/*.mat'])
                end
            end


            % Create the transfer files
%             Config_folder='./Temps/';
            file_name   = 'transfer.mat';
            Tras_FileName    =...
                fullfile([Config_folder, 'YearFr'], file_name);
            
            
            file_name   = sprintf('YrwdSpec_%sY%s.mat', num2str(1), num2str(Start_Y));
            
            fullpath    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
            
            if ~isfile(fullpath)
                
                num_LAD= 20;
                num_root=12;
                
                dat_LAD = zeros(3,2);
                
                litter_depth = 0.0; % [m]
                
                opt_root=1;
                
                dat_root = zeros(3,2);
                
                set_para_root   = {...
                    ' Maximum depth'       , 1     ,   ; ...
                    ' z50'                 , 0.07  ,   ; ...
                    ' z95'                 , 0.4   ,   ; ...
                    ' # layer to cut root' , 1          ....
                    };
                
                set_root_func = {...
                    ' a'                , 0.032 ,    ; ...
                    ' b'                , 0.5          ...
                    };
                
                
            else
                
                load(fullpath, 'dat_LAD', 'num_LAD', 'litter_depth',...
                    'opt_root', 'dat_root', 'set_para_root', 'set_root_func',...
                    'num_root')
                
            end
            
            save(Tras_FileName, 'dat_LAD', 'num_LAD', 'litter_depth',...
                'opt_root', 'dat_root', 'set_para_root', 'set_root_func',...
                'num_root')
            
        end

        % Value changed function: YearsList
        function YearsListValueChanged(app, event)
            
            load('.\Temps\temporary.mat', 'config_path');
            
            
            Config_folder=config_path;
            
            value = app.YearsList.Value;
            value1 = app.SpeciesDropDown.Value;
            
            if strcmpi(value, 'Select Year')
                app.GrDetails.Visible='off';
                app.SaveButton.Visible='off';
                
            else
                app.GrDetails.Visible='on';
                
                app.GrDetails.SelectedTab=app.GrDetails.Children(1);
                
                app.SaveButton.Visible='on';
                
                file_name   = sprintf('YrwdSpec_%sY%s.mat',...
                    num2str(value1), num2str(value));
                fullpath    =...
                    fullfile(fullfile(Config_folder, 'YearFr'), file_name);
                
                if isfile(fullpath)
                    
                    load (fullpath, 'Ph_Type', 'Vegetation', 'crop_name',...
                        'num_LAD', 'num_root', 'dat_LAD', 'dat_root',...
                        'PlantingDOY', 'HarvestDOY',...
                        'Irrigated', 'Fertilized', 'IrrType', 'NitType',...
                        'IrrData', 'NitData')
                    
                    app.VegetationSwitch.Value        =Vegetation;
                    
                    if strcmpi(Vegetation, 'No')
                        
                        app.VegetationNameEditField.Enable='off';
                        app.PhotosynthesisLabel.Enable='off';
                        app.C3Button.Enable='off';
                        app.C4Button.Enable='off';
                        
                        app.CanopyLayersEditField.Enable='off';
                        app.AddEditButton.Enable='off';
                        app.Load.Enable='off';
                        app.Plot_CStructure.Visible='off';
                        cla(app.Plot_CStructure)
                        
                        app.RootLayersEditField.Enable='off';
                        app.AddEditButton_2.Enable='off';
                        app.Load_2.Enable='off';
                        app.Plot_RStructure.Visible='off';
                        cla(app.Plot_RStructure)
                        
                        app.PlantingDOYEditField.Enable='off';
                        app.HarvestDOYEditField.Enable='off';
                        
                        app.IrrigatedCheckBox.Enable='off';
                        app.MethodofIrrigationListBox.Enable='off';
                        app.IrrigationschedulesLabel.Enable='off';
                        app.IrrTable.Enable='off';
                        app.AddIrrigationButton.Enable='off';
                        app.DelIrrigationButton.Enable='off';
                        
                        app.FertilizersAppliedCheckBox.Enable='off';
                        app.MethodofFertilizersListBox.Enable='off';
                        app.NitrogenFertilizersschedulesLabel.Enable='off';
                        app.NitTable.Enable='off';
                        app.AddFertilizerButton.Enable='off';
                        app.DelFertilizerButton.Enable='off';
                        
                        
                    else
                        
                        app.VegetationNameEditField.Enable='on';
                        app.PhotosynthesisLabel.Enable='on';
                        app.C3Button.Enable='on';
                        app.C4Button.Enable='on';
                        
                        app.CanopyLayersEditField.Enable='on';
                        app.AddEditButton.Enable='on';
                        app.Load.Enable='on';
                        app.Plot_CStructure.Visible='on';
                        
                        app.RootLayersEditField.Enable='on';
                        app.AddEditButton_2.Enable='on';
                        app.Load_2.Enable='on';
                        app.Plot_RStructure.Visible='on';
                        
                        app.PlantingDOYEditField.Enable='on';
                        app.HarvestDOYEditField.Enable='on';
                        
                        app.IrrigatedCheckBox.Enable='on';
                        
                        app.FertilizersAppliedCheckBox.Enable='on';
                        
                        app.VegetationNameEditField.Value =crop_name;
                        app.C3Button.Value=Ph_Type;
                        
                        app.CanopyLayersEditField.Value=num_LAD;
                        
                        canopy_height   = dat_LAD(:,1);
                        LAI             = dat_LAD(:,2);
                        
                        app.Plot_CStructure.Visible='on';
                        
                        plot(app.Plot_CStructure, LAI, canopy_height,'Color', 'b',...
                            'LineStyle','--','LineWidth',2,'Marker','s','MarkerEdgeColor','k',...
                            'MarkerFaceColor','g','MarkerSize',6);
                        
                        app.Plot_CStructure.Title.String='Leaf Area Density Profile';
                        
                        app.Plot_CStructure.XLabel.String='Normalized LAD [-]';
                        app.Plot_CStructure.YLabel.String='Canopy height [m]';
                        
                        
                        app.RootLayersEditField.Value=num_root;
                        
                        root_depth  = dat_root(:,1);
                        RLD         = dat_root(:,2);
                        
                        app.Plot_RStructure.Visible='on';
                        
                        plot(app.Plot_RStructure, RLD,root_depth,'Color', 'r',...
                            'LineStyle','--','LineWidth',2,'Marker','s','MarkerEdgeColor','k',...
                            'MarkerFaceColor','g','MarkerSize',6);
                        
                        app.Plot_RStructure.Title.String='Root Profile';
                        
                        app.Plot_RStructure.XLabel.String='Root Density [m m^{-3}]';
                        app.Plot_RStructure.YLabel.String='Root Depth [m]';
                        
                        
                        app.PlantingDOYEditField.Value  =PlantingDOY;
                        app.HarvestDOYEditField.Value   =HarvestDOY;
                        
                        app.IrrigatedCheckBox.Value     =Irrigated;
                        app.FertilizersAppliedCheckBox.Value =Fertilized;
                        
                        if Irrigated==0
                            
                            app.MethodofIrrigationListBox.Value='Manually';
                            app.MethodofIrrigationListBox.Enable='off';
                            app.IrrTable.Enable               ='off';
                            app.IrrTable.Data               =[];
                            app.AddIrrigationButton.Enable ='off';
                            app.DelIrrigationButton.Enable ='off';
                            
                        else
                            app.MethodofIrrigationListBox.Value=IrrType;
                            app.MethodofIrrigationListBox.Enable='on';
                            
                            if strcmpi(IrrType, 'Manually')
                                app.IrrTable.Data               =IrrData;
                                app.IrrTable.Enable               ='on';
                                app.AddIrrigationButton.Enable ='on';
                                app.DelIrrigationButton.Enable ='on';
                            else
                                app.IrrTable.Data               =[];
                                app.IrrTable.Enable               ='off';
                                app.AddIrrigationButton.Enable ='off';
                                app.DelIrrigationButton.Enable ='off';
                            end
                            
                        end
                        
                        if Fertilized==0
                            app.MethodofFertilizersListBox.Value='Manually';
                            app.MethodofFertilizersListBox.Enable='off';
                            app.NitTable.Data               =[];
                            app.NitTable.Enable               ='off';
                            app.AddFertilizerButton.Enable ='off';
                            app.DelFertilizerButton.Enable ='off';
                        else
                            app.MethodofFertilizersListBox.Value=NitType;
                            app.MethodofFertilizersListBox.Enable='on';
                            
                            if strcmpi(NitType, 'Manually')
                                app.NitTable.Data               =NitData;
                                app.NitTable.Enable               ='on';
                                app.AddFertilizerButton.Enable ='on';
                                app.DelFertilizerButton.Enable ='on';
                                
                            else
                                app.NitTable.Data               =[];
                                app.NitTable.Enable               ='off';
                                app.AddFertilizerButton.Enable ='off';
                                app.DelFertilizerButton.Enable ='off';
                            end
                            
                        end
                    end
                    
                    app.SavedLamp.Color='green';
                else
                    
                    app.VegetationSwitch.Value='No';
                    
                    app.VegetationNameEditField.Value ='';
                    
                    app.VegetationNameEditField.Enable='off';
                    app.PhotosynthesisLabel.Enable='off';
                    app.C3Button.Enable='off';
                    app.C4Button.Enable='off';
                    
                    app.CanopyLayersEditField.Enable='off';
                    app.AddEditButton.Enable='off';
                    app.Load.Enable='off';
                    app.Plot_CStructure.Visible='off';
                    cla(app.Plot_CStructure)
                    
                    app.RootLayersEditField.Enable='off';
                    app.AddEditButton_2.Enable='off';
                    app.Load_2.Enable='off';
                    app.Plot_RStructure.Visible='off';
                    cla(app.Plot_RStructure)
                    
                    app.PlantingDOYEditField.Enable='off';
                    app.HarvestDOYEditField.Enable='off';
                    
                    app.PlantingDOYEditField.Value  =1;
                    app.HarvestDOYEditField.Value   =365;
                    
                    %Irrigation
                    app.IrrigatedCheckBox.Value     =0;
                    app.MethodofIrrigationListBox.Value='Manually';
                    app.MethodofIrrigationListBox.Enable='off';
                    app.IrrTable.Data               =[];
                    app.IrrTable.Enable='off';
                    app.AddIrrigationButton.Enable ='off';
                    app.DelIrrigationButton.Enable ='off';
                    
                    %Fertilizer
                    app.FertilizersAppliedCheckBox.Value=0;
                    app.MethodofFertilizersListBox.Value='Manually';
                    app.MethodofFertilizersListBox.Enable='off';
                    app.NitTable.Data               =[];
                    app.NitTable.Enable='off';
                    app.AddFertilizerButton.Enable ='off';
                    app.DelFertilizerButton.Enable ='off';
                    
                    app.SavedLamp.Color='red';
                    
                end
                
            end
            
        end

        % Value changed function: VegetationSwitch
        function VegetationSwitchValueChanged(app, event)
            
            Vegetation = app.VegetationSwitch.Value;
            
            if strcmpi(Vegetation, 'Yes')
                
                app.VegetationNameEditField.Enable='on';
                app.PhotosynthesisLabel.Enable='on';
                app.C3Button.Enable='on';
                app.C4Button.Enable='on';
                
                app.CanopyLayersEditField.Enable='on';
                app.AddEditButton.Enable='on';
                app.Load.Enable='on';
                app.Plot_CStructure.Visible='off';
                cla(app.Plot_CStructure)
                
                app.RootLayersEditField.Enable='on';
                app.AddEditButton_2.Enable='on';
                app.Load_2.Enable='on';
                app.Plot_RStructure.Visible='off';
                cla(app.Plot_RStructure)
                
                app.PlantingDOYEditField.Enable='on';
                app.HarvestDOYEditField.Enable='on';
                
                app.IrrigatedCheckBox.Enable='on';
                app.IrrigatedCheckBox.Value=0;
                app.MethodofIrrigationListBox.Value='Manually';
                app.IrrTable.Data=[];
                app.IrrTable.Enable='off';
                app.AddIrrigationButton.Enable='off';
                app.DelIrrigationButton.Enable='off';
                
                app.FertilizersAppliedCheckBox.Enable='on';
                app.FertilizersAppliedCheckBox.Value=0;
                app.MethodofFertilizersListBox.Value='Manually';
                app.NitTable.Data=[];
                app.IrrTable.Enable='off';
                app.AddFertilizerButton.Enable='off';
                app.DelFertilizerButton.Enable='off';
                
            elseif strcmpi(Vegetation, 'No')
                
                app.VegetationNameEditField.Enable='off';
                app.PhotosynthesisLabel.Enable='off';
                app.C3Button.Enable='off';
                app.C4Button.Enable='off';
                
                app.CanopyLayersEditField.Enable='off';
                app.AddEditButton.Enable='off';
                app.Load.Enable='off';
                app.Plot_CStructure.Visible='off';
                cla(app.Plot_CStructure)
                
                app.RootLayersEditField.Enable='off';
                app.AddEditButton_2.Enable='off';
                app.Load_2.Enable='off';
                app.Plot_RStructure.Visible='off';
                cla(app.Plot_RStructure)
                
                app.PlantingDOYEditField.Enable='off';
                app.HarvestDOYEditField.Enable='off';
                
                app.IrrigatedCheckBox.Enable='off';
                app.IrrigatedCheckBox.Value=0;
                app.MethodofIrrigationListBox.Value='Manually';
                app.IrrTable.Data=[];
                app.IrrTable.Enable='off';
                app.AddIrrigationButton.Enable='off';
                app.DelIrrigationButton.Enable='off';
                
                app.FertilizersAppliedCheckBox.Enable='off';
                app.FertilizersAppliedCheckBox.Value=0;
                app.MethodofFertilizersListBox.Value='Manually';
                app.NitTable.Data=[];
                app.IrrTable.Enable='off';
                app.AddFertilizerButton.Enable='off';
                app.DelFertilizerButton.Enable='off';
                
            end
            
            
        end

        % Value changed function: IrrigatedCheckBox
        function IrrigatedCheckBoxValueChanged(app, event)
            value = app.IrrigatedCheckBox.Value;
            if value==1
                app.MethodofIrrigationListBox.Value='Manually';
                app.MethodofIrrigationListBox.Enable='on';
                app.IrrTable.Enable='on';
                app.IrrigationschedulesLabel.Enable='on';
                app.AddIrrigationButton.Enable='on';
                app.DelIrrigationButton.Enable='on';
                
            else
                app.MethodofIrrigationListBox.Value='Manually';
                app.MethodofIrrigationListBox.Enable='off';
                app.IrrTable.Enable='off';
                app.IrrigationschedulesLabel.Enable='off';
                app.AddIrrigationButton.Enable='off';
                app.DelIrrigationButton.Enable='off';
                
            end
        end

        % Value changed function: FertilizersAppliedCheckBox
        function FertilizersAppliedCheckBoxValueChanged(app, event)
            value = app.FertilizersAppliedCheckBox.Value;
            if value==1
                app.MethodofFertilizersListBox.Value='Manually';
                app.MethodofFertilizersListBox.Enable='on';
                app.NitTable.Enable='on';
                app.NitrogenFertilizersschedulesLabel.Enable='on';
                app.AddFertilizerButton.Enable='on';
                app.DelFertilizerButton.Enable='on';
                
            else
                app.MethodofFertilizersListBox.Value='Manually';
                app.MethodofFertilizersListBox.Enable='off';
                app.NitTable.Enable='off';
                app.NitrogenFertilizersschedulesLabel.Enable='off';
                app.AddFertilizerButton.Enable='off';
                app.DelFertilizerButton.Enable='off';
                
            end
        end

        % Value changed function: MethodofIrrigationListBox
        function MethodofIrrigationListBoxValueChanged(app, event)
            value = app.MethodofIrrigationListBox.Value;
            
            if strcmpi(value, 'Manually')
                app.IrrTable.Enable='on';
                app.IrrigationschedulesLabel.Enable='on';
                app.AddIrrigationButton.Enable='on';
                app.DelIrrigationButton.Enable='on';
            else
                app.IrrTable.Enable='off';
                app.IrrigationschedulesLabel.Enable='off';
                app.AddIrrigationButton.Enable='off';
                app.DelIrrigationButton.Enable='off';
            end
        end

        % Value changed function: MethodofFertilizersListBox
        function MethodofFertilizersListBoxValueChanged(app, event)
            value = app.MethodofFertilizersListBox.Value;
            
            if strcmpi(value, 'Manually')
                app.NitTable.Enable='on';
                app.NitrogenFertilizersschedulesLabel.Enable='on';
                app.AddFertilizerButton.Enable='on';
                app.DelFertilizerButton.Enable='on';
            else
                app.NitTable.Enable='off';
                app.NitrogenFertilizersschedulesLabel.Enable='off';
                app.AddFertilizerButton.Enable='off';
                app.DelFertilizerButton.Enable='off';
            end
            
        end

        % Button pushed function: AddIrrigationButton
        function AddIrrigationButtonPushed(app, event)
            nr={nan nan};
            
            t=app.IrrTable.Data;
            app.IrrTable.Data=[t;nr];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            value = app.YearsList.Value;
            value1=app.SpeciesDropDown.Value;
            
            load('.\Temps\temporary.mat', 'config_path');

            Config_folder=config_path;
            
            file_name           = sprintf('YrwdSpec_%sY%s.mat', num2str(value1), num2str(value));
            fullpath    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
            
            
            Vegetation=app.VegetationSwitch.Value;
            crop_name=app.VegetationNameEditField.Value;
            Ph_Type=app.C3Button.Value;
            
            %             num_LAD=app.CanopyLayersEditField.Value;
            %
            %             num_root=app.RootLayersEditField.Value;
            
            PlantingDOY=app.PlantingDOYEditField.Value;
            HarvestDOY=app.HarvestDOYEditField.Value;
            
            Irrigated=app.IrrigatedCheckBox.Value;
            IrrType=app.MethodofIrrigationListBox.Value;
            IrrData=app.IrrTable.Data;
            
            Fertilized=app.FertilizersAppliedCheckBox.Value;
            NitType=app.MethodofFertilizersListBox.Value;
            NitData=app.NitTable.Data;
            
%             Config_folder='./Temps/';
            file_name   = 'transfer.mat';
            Tras_FileName    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
            
            load(Tras_FileName, 'dat_LAD', 'num_LAD', 'litter_depth',...
                'opt_root', 'dat_root', 'set_para_root', 'set_root_func',...
                'num_root')
            
            
            save (fullpath, 'Ph_Type', 'Vegetation', 'crop_name',...
                'num_LAD', 'num_root', 'PlantingDOY', 'HarvestDOY',...
                'Irrigated', 'Fertilized', 'IrrType', 'NitType',...
                'IrrData', 'NitData', ...
                ...
                'dat_LAD', 'num_LAD', 'litter_depth',...
                'opt_root', 'dat_root', 'set_para_root', 'set_root_func',...
                'num_root')
            
            app.SavedLamp.Color='green';
            
        end

        % Button pushed function: DelIrrigationButton
        function DelIrrigationButtonPushed(app, event)
            
            if size(app.selcI)>0
                Sel_row=app.selcI(1);
                app.IrrTable.Data(Sel_row,:)=[];
            end
            
        end

        % Cell selection callback: IrrTable
        function IrrTableCellSelection(app, event)
            indices = event.Indices;
            app.selcI=indices;
            %
            %             disp(app.selcI)
        end

        % Cell selection callback: NitTable
        function NitTableCellSelection(app, event)
            indices = event.Indices;
            app.selcN=indices;
        end

        % Button pushed function: DelFertilizerButton
        function DelFertilizerButtonPushed(app, event)
            if size(app.selcN)>0
                Sel_row=app.selcN(1);
                app.NitTable.Data(Sel_row,:)=[];
            end
        end

        % Button pushed function: AddFertilizerButton
        function AddFertilizerButtonPushed(app, event)
            
            nr={nan nan nan nan};
            %             nr={[] [] [] []};
            %             t=app.NitTable.Data;
            app.NitTable.Data=[app.NitTable.Data; nr];
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;
            
            value = app.YearsList.Value;
            value1 = app.SpeciesDropDown.Value;
            
            if strcmpi(value, 'Select Year')
                app.GrDetails.Visible='off';
                app.SaveButton.Visible='off';
            else
                app.GrDetails.Visible='on';
                
                app.GrDetails.SelectedTab=app.GrDetails.Children(1);
                
                app.SaveButton.Visible='on';
                
                file_name   = sprintf('YrwdSpec_%sY%s.mat', num2str(value1), num2str(value));
                fullpath    =...
                    fullfile(fullfile(Config_folder, 'YearFr'), file_name);
                
                if isfile(fullpath)
                    
                    load (fullpath, 'Ph_Type', 'Vegetation', 'crop_name',...
                        'num_LAD', 'num_root',...
                        'PlantingDOY', 'HarvestDOY',...
                        'Irrigated', 'Fertilized', 'IrrType', 'NitType',...
                        'IrrData', 'NitData', 'dat_root','dat_LAD', 'litter_depth')
                    
                    
                    app.VegetationSwitch.Value        =Vegetation;
                    
                    if strcmpi(Vegetation, 'No')
                        
                        app.VegetationNameEditField.Enable='off';
                        app.PhotosynthesisLabel.Enable='off';
                        app.C3Button.Enable='off';
                        app.C4Button.Enable='off';
                        
                        app.CanopyLayersEditField.Enable='off';
                        app.AddEditButton.Enable='off';
                        app.Load.Enable='off';
                        app.Plot_CStructure.Visible='off';
                        cla(app.Plot_CStructure)
                        
                        app.RootLayersEditField.Enable='off';
                        app.AddEditButton_2.Enable='off';
                        app.Load_2.Enable='off';
                        app.Plot_RStructure.Visible='off';
                        cla(app.Plot_RStructure)
                        
                        app.PlantingDOYEditField.Enable='off';
                        app.HarvestDOYEditField.Enable='off';
                        
                        app.IrrigatedCheckBox.Enable='off';
                        app.MethodofIrrigationListBox.Enable='off';
                        app.IrrigationschedulesLabel.Enable='off';
                        app.IrrTable.Enable='off';
                        app.AddIrrigationButton.Enable='off';
                        app.DelIrrigationButton.Enable='off';
                        
                        app.FertilizersAppliedCheckBox.Enable='off';
                        app.MethodofFertilizersListBox.Enable='off';
                        app.NitrogenFertilizersschedulesLabel.Enable='off';
                        app.NitTable.Enable='off';
                        app.AddFertilizerButton.Enable='off';
                        app.DelFertilizerButton.Enable='off';
                        
                        
                    else
                        
                        app.VegetationNameEditField.Enable='on';
                        app.PhotosynthesisLabel.Enable='on';
                        app.C3Button.Enable='on';
                        app.C4Button.Enable='on';
                        
                        app.CanopyLayersEditField.Enable='on';
                        app.AddEditButton.Enable='on';
                        app.Load.Enable='on';
                        app.Plot_CStructure.Visible='on';
                        
                        app.RootLayersEditField.Enable='on';
                        app.AddEditButton_2.Enable='on';
                        app.Load_2.Enable='on';
                        app.Plot_RStructure.Visible='on';
                        
                        app.PlantingDOYEditField.Enable='on';
                        app.HarvestDOYEditField.Enable='on';
                        
                        app.IrrigatedCheckBox.Enable='on';
                        
                        app.FertilizersAppliedCheckBox.Enable='on';
                        
                        app.VegetationNameEditField.Value =crop_name;
                        app.C3Button.Value=Ph_Type;
                        
                        app.CanopyLayersEditField.Value=num_LAD;
                        
                        canopy_height   = dat_LAD(:,1);
                        LAI             = dat_LAD(:,2);
                        
                        app.Plot_CStructure.Visible='on';
                        
                        plot(app.Plot_CStructure, LAI, canopy_height,'Color', 'b',...
                            'LineStyle','--','LineWidth',2,'Marker','s','MarkerEdgeColor','k',...
                            'MarkerFaceColor','g','MarkerSize',6);
                        
                        app.Plot_CStructure.Title.String='Leaf Area Density Profile';
                        
                        app.Plot_CStructure.XLabel.String='Normalized LAD [-]';
                        app.Plot_CStructure.YLabel.String='Canopy height [m]';
                        
                        
                        app.RootLayersEditField.Value=num_root;
                        
                        root_depth  = dat_root(:,1);
                        RLD         = dat_root(:,2);
                        
                        app.Plot_RStructure.Visible='on';
                        
                        plot(app.Plot_RStructure, RLD,root_depth,'Color', 'r',...
                            'LineStyle','--','LineWidth',2,'Marker','s','MarkerEdgeColor','k',...
                            'MarkerFaceColor','g','MarkerSize',6);
                        
                        app.Plot_RStructure.Title.String='Root Profile';
                        
                        app.Plot_RStructure.XLabel.String='Root Density [m m^{-3}]';
                        app.Plot_RStructure.YLabel.String='Root Depth [m]';
                        
                        
                        app.PlantingDOYEditField.Value  =PlantingDOY;
                        app.HarvestDOYEditField.Value   =HarvestDOY;
                        
                        app.IrrigatedCheckBox.Value     =Irrigated;
                        app.FertilizersAppliedCheckBox.Value =Fertilized;
                        
                        if Irrigated==0
                            
                            app.MethodofIrrigationListBox.Value='Manually';
                            app.MethodofIrrigationListBox.Enable='off';
                            app.IrrTable.Enable               ='off';
                            app.IrrTable.Data               =[];
                            app.AddIrrigationButton.Enable ='off';
                            app.DelIrrigationButton.Enable ='off';
                            
                        else
                            app.MethodofIrrigationListBox.Value=IrrType;
                            app.MethodofIrrigationListBox.Enable='on';
                            
                            if strcmpi(IrrType, 'Manually')
                                app.IrrTable.Data               =IrrData;
                                app.IrrTable.Enable               ='on';
                                app.AddIrrigationButton.Enable ='on';
                                app.DelIrrigationButton.Enable ='on';
                            else
                                app.IrrTable.Data               =[];
                                app.IrrTable.Enable               ='off';
                                app.AddIrrigationButton.Enable ='off';
                                app.DelIrrigationButton.Enable ='off';
                            end
                            
                        end
                        
                        if Fertilized==0
                            app.MethodofFertilizersListBox.Value='Manually';
                            app.MethodofFertilizersListBox.Enable='off';
                            app.NitTable.Data               =[];
                            app.NitTable.Enable               ='off';
                            app.AddFertilizerButton.Enable ='off';
                            app.DelFertilizerButton.Enable ='off';
                        else
                            app.MethodofFertilizersListBox.Value=NitType;
                            app.MethodofFertilizersListBox.Enable='on';
                            
                            if strcmpi(NitType, 'Manually')
                                app.NitTable.Data               =NitData;
                                app.NitTable.Enable               ='on';
                                app.AddFertilizerButton.Enable ='on';
                                app.DelFertilizerButton.Enable ='on';
                                
                            else
                                app.NitTable.Data               =[];
                                app.NitTable.Enable               ='off';
                                app.AddFertilizerButton.Enable ='off';
                                app.DelFertilizerButton.Enable ='off';
                            end
                            
                        end
                    end
                    
                    app.SavedLamp.Color='green';
                else
                    
                    app.VegetationSwitch.Value='No';
                    
                    app.VegetationNameEditField.Value ='';
                    
                    app.VegetationNameEditField.Enable='off';
                    app.PhotosynthesisLabel.Enable='off';
                    app.C3Button.Enable='off';
                    app.C4Button.Enable='off';
                    
                    litter_depth = 0.0; % [m]
                    
                    save(fullpath, 'litter_depth')
                    
                    app.CanopyLayersEditField.Enable='off';
                    app.AddEditButton.Enable='off';
                    app.Load.Enable='off';
                    app.Plot_CStructure.Visible='off';
                    cla(app.Plot_CStructure)
                    
                    app.RootLayersEditField.Enable='off';
                    app.AddEditButton_2.Enable='off';
                    app.Load_2.Enable='off';
                    app.Plot_RStructure.Visible='off';
                    cla(app.Plot_RStructure)
                    
                    app.PlantingDOYEditField.Enable='off';
                    app.HarvestDOYEditField.Enable='off';
                    
                    app.PlantingDOYEditField.Value  =1;
                    app.HarvestDOYEditField.Value   =365;
                    
                    %Irrigation
                    app.IrrigatedCheckBox.Value     =0;
                    app.MethodofIrrigationListBox.Value='Manually';
                    app.MethodofIrrigationListBox.Enable='off';
                    app.IrrTable.Data               =[];
                    app.IrrTable.Enable='off';
                    app.AddIrrigationButton.Enable ='off';
                    app.DelIrrigationButton.Enable ='off';
                    
                    %Fertilizer
                    app.FertilizersAppliedCheckBox.Value=0;
                    app.MethodofFertilizersListBox.Value='Manually';
                    app.MethodofFertilizersListBox.Enable='off';
                    app.NitTable.Data               =[];
                    app.NitTable.Enable='off';
                    app.AddFertilizerButton.Enable ='off';
                    app.DelFertilizerButton.Enable ='off';
                    
                    app.SavedLamp.Color='red';
                    
                end
                
            end
            
            
        end

        % Callback function
        function MethodofIrrigationListBoxValueChanged2(app, event)
            value = app.MethodofIrrigationListBox.Value;
            
        end

        % Selection change function: GrDetails
        function GrDetailsSelectionChanged(app, event)
            selectedTab = app.GrDetails.SelectedTab;
        end

        % Button pushed function: AddEditButton
        function AddEditButtonPushed(app, event)
            
            num_LAD=app.CanopyLayersEditField.Value;
            
            cla(app.Plot_CStructure)
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;            
            
            file_name   = 'transfer.mat';
            Tras_FileName    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
            
            save(Tras_FileName, "num_LAD", ...
                '-append')
            
            run('setup_LAD_profile_M.m')
            
        end

        % Button pushed function: AddEditButton_2
        function AddEditButton_2Pushed(app, event)
            
            num_root=app.RootLayersEditField.Value;
            
            working_year=app.YearsList.Value;
            working_spec=app.SpeciesDropDown.Value;
            
            cla(app.Plot_RStructure)
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;    
            
            file_name   = 'transfer.mat';
            Tras_FileName    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
            
            save(Tras_FileName, "num_root", 'working_year', "working_spec",...
                '-append')
            
            run('setup_root_profile_M.m')
            
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;    
            
            file_name   = 'transfer.mat';
            
            Tras_FileName    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
            
            
            load(Tras_FileName, 'dat_LAD');
            
            canopy_height   = dat_LAD(:,1);
            LAI             = dat_LAD(:,2);
            
            app.Plot_CStructure.Visible='on';
            
            plot(app.Plot_CStructure, LAI, canopy_height,'Color', 'b',...
                'LineStyle','--','LineWidth',2,'Marker','s','MarkerEdgeColor','k',...
                'MarkerFaceColor','g','MarkerSize',6);
            
            app.Plot_CStructure.Title.String='Leaf Area Density Profile';
            
            app.Plot_CStructure.XLabel.String='Normalized LAD [-]';
            app.Plot_CStructure.YLabel.String='Canopy height [m]';
            
        end

        % Button pushed function: Load_2
        function Load_2ButtonPushed(app, event)
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;    
            
            file_name   = 'transfer.mat';
            
            Tras_FileName    =...
                fullfile(fullfile(Config_folder, 'YearFr'), file_name);
                        
            load(Tras_FileName, 'dat_root');
            
            root_depth  = dat_root(:,1);
            RLD         = dat_root(:,2);
            
            app.Plot_RStructure.Visible='on';
            
            plot(app.Plot_RStructure, RLD,root_depth,'Color', 'r',...
                'LineStyle','--','LineWidth',2,'Marker','s','MarkerEdgeColor','k',...
                'MarkerFaceColor','g','MarkerSize',6);
            
            app.Plot_RStructure.Title.String='Root Profile';
            
            app.Plot_RStructure.XLabel.String='Root Density [m m^{-3}]';
            app.Plot_RStructure.YLabel.String='Root Depth [m]';
            
        end

        % Value changed function: NitrogenAllocationModelCheckBox
        function NitrogenAllocationModelCheckBoxValueChanged(app, event)
            value = app.NitrogenAllocationModelCheckBox.Value;
            
            if value==1
                app.ParrallelComputingSwitch.Enable='on';
            else
                app.ParrallelComputingSwitch.Enable='off';
            end
                        
        end

        % Value changed function: ParrallelComputingSwitch
        function ParrallelComputingSwitchValueChanged(app, event)
            value = app.ParrallelComputingSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'option.png';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Multi-Layer Canopy Soil Model';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = ' OPTIONS';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SUBMODELSTab = uitab(app.TabGroup);
            app.SUBMODELSTab.Title = 'SUB-MODELS';
            app.SubmodelsPanel = uipanel(app.SUBMODELSTab);
            app.SubmodelsPanel.Title = 'Sub-models';
            app.CropGrowthProcessCheckBox = uicheckbox(app.SubmodelsPanel);
            app.CropGrowthProcessCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropGrowthProcessCheckBoxValueChanged, true);
            app.CropGrowthProcessCheckBox.Text = 'Crop Growth Process';
            app.TurbulenceCheckBox = uicheckbox(app.SubmodelsPanel);
            app.TurbulenceCheckBox.Text = 'Turbulence';
            app.HydraulicRedistributionHRCheckBox = uicheckbox(app.SubmodelsPanel);
            app.HydraulicRedistributionHRCheckBox.Text = 'Hydraulic Redistribution (HR)';
            app.SoilHeatModelCheckBox = uicheckbox(app.SubmodelsPanel);
            app.SoilHeatModelCheckBox.Text = 'Soil Heat Model';
            app.SoilNutrientModelCheckBox = uicheckbox(app.SubmodelsPanel);
            app.SoilNutrientModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SoilNutrientModelCheckBoxValueChanged, true);
            app.SoilNutrientModelCheckBox.Text = 'Soil Nutrient Model';
            app.DenitrificationCheckBox = uicheckbox(app.SubmodelsPanel);
            app.DenitrificationCheckBox.Text = 'Denitrification';
            app.AtmosphericDepositionCheckBox = uicheckbox(app.SubmodelsPanel);
            app.AtmosphericDepositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AtmosphericDepositionCheckBoxValueChanged, true);
            app.AtmosphericDepositionCheckBox.Text = 'Atmospheric Deposition';
            app.gNm2yrLabel = uilabel(app.SubmodelsPanel);
            app.gNm2yrLabel.Text = '(gN/m2/yr)';
            app.AmmoniumLabel = uilabel(app.SubmodelsPanel);
            app.AmmoniumLabel.Text = 'Ammonium';
            app.AmmoniumEditField = uieditfield(app.SubmodelsPanel, 'numeric');
            app.NitrateLabel = uilabel(app.SubmodelsPanel);
            app.NitrateLabel.Text = 'Nitrate';
            app.NitrateEditField = uieditfield(app.SubmodelsPanel, 'numeric');
            app.NitrogenAllocationModelCheckBox = uicheckbox(app.SubmodelsPanel);
            app.NitrogenAllocationModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @NitrogenAllocationModelCheckBoxValueChanged, true);
            app.NitrogenAllocationModelCheckBox.Text = 'Nitrogen-Allocation Model';
            app.ParrallelComputingSwitchLabel = uilabel(app.SubmodelsPanel);
            app.ParrallelComputingSwitchLabel.Text = 'Parrallel Computing';
            app.ParrallelComputingSwitch = uiswitch(app.SubmodelsPanel, 'slider');
            app.ParrallelComputingSwitch.ValueChangedFcn = createCallbackFcn(app, @ParrallelComputingSwitchValueChanged, true);
            app.text4 = uilabel(app.SUBMODELSTab);
            app.text4.Tag = 'text3';
            app.text4.Text = {'Check boxes to include/exclude sub-models in MLCan '; ''; '  - Crop Growth Process: '; ''; '  - Turbulence: Calculating turbulence transport of scalar micro-environment using a '; '     first-order canopy closure model.'; ''; '  - Hydraulic Redistribution: Simulating the translocation of soil moisture via plant '; '    root systems.'; ''; '  - Soil Heat Model: Simulating heat transfer within the soil '; ''; '  - Soil Nutrient Model: Simulating soil carbon and nitrogen process'; ''; '  - Denitrification: Simulating a microbially facilitated process of soil nitrate reduction '; '  '; '  - Atmospheric Decomposition: Considering uniform atmospheric nitrogen '; '    depositions'; ''; '  - Nitrogen-Allocation Model:'; ''};
            app.CANOPYPROCESSTab = uitab(app.TabGroup);
            app.CANOPYPROCESSTab.Title = 'CANOPY PROCESS';
            app.SpeciesButtonGroup = uibuttongroup(app.CANOPYPROCESSTab);
            app.SpeciesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButtonGroupSelectionChanged, true);
            app.SpeciesButtonGroup.Title = 'Species';
            app.SinglespeciessimulationButton = uiradiobutton(app.SpeciesButtonGroup);
            app.SinglespeciessimulationButton.Text = 'Single species simulation';
            app.SinglespeciessimulationButton.Value = true;
            app.MultispeciesButton = uiradiobutton(app.SpeciesButtonGroup);
            app.MultispeciesButton.Text = 'Multi species ';
            app.NumbersEditField = uieditfield(app.SpeciesButtonGroup, 'numeric');
            app.NumbersEditField.Value = 1;
            app.WaterRetentionCurveButtonGroup = uibuttongroup(app.CANOPYPROCESSTab);
            app.WaterRetentionCurveButtonGroup.Title = 'Water Retention Curve';
            app.vanGenButton = uiradiobutton(app.WaterRetentionCurveButtonGroup);
            app.vanGenButton.Text = 'vanGen';
            app.vanGenButton.Value = true;
            app.BrooksButton = uiradiobutton(app.WaterRetentionCurveButtonGroup);
            app.BrooksButton.Text = 'Brooks';
            app.RootHydraulicConductivityButtonGroup = uibuttongroup(app.CANOPYPROCESSTab);
            app.RootHydraulicConductivityButtonGroup.Title = 'Root Hydraulic Conductivity';
            app.LinearButton = uiradiobutton(app.RootHydraulicConductivityButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.NochangeButton = uiradiobutton(app.RootHydraulicConductivityButtonGroup);
            app.NochangeButton.Text = 'No change';
            app.CO2ConcentrationPPMButtonGroup = uibuttongroup(app.CANOPYPROCESSTab);
            app.CO2ConcentrationPPMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CO2ConcentrationPPMButtonGroupSelectionChanged, true);
            app.CO2ConcentrationPPMButtonGroup.Title = 'CO2 Concentration (PPM)';
            app.AmbientButton = uiradiobutton(app.CO2ConcentrationPPMButtonGroup);
            app.AmbientButton.Text = 'Ambient';
            app.AmbientButton.Value = true;
            app.NumbersEditField_2 = uieditfield(app.CO2ConcentrationPPMButtonGroup, 'numeric');
            app.NumbersEditField_2.Editable = 'off';
            app.NumbersEditField_2.Enable = 'off';
            app.NumbersEditField_2.Value = 400;
            app.ElevatedButton = uiradiobutton(app.CO2ConcentrationPPMButtonGroup);
            app.ElevatedButton.Text = 'Elevated';
            app.NumbersEditField_3 = uieditfield(app.CO2ConcentrationPPMButtonGroup, 'numeric');
            app.NumbersEditField_3.Value = 550;
            app.TemperatureChangeCButtonGroup = uibuttongroup(app.CANOPYPROCESSTab);
            app.TemperatureChangeCButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TemperatureChangeCButtonGroupSelectionChanged, true);
            app.TemperatureChangeCButtonGroup.Title = 'Temperature Change ("C)';
            app.AmbientButton_2 = uiradiobutton(app.TemperatureChangeCButtonGroup);
            app.AmbientButton_2.Text = 'Ambient';
            app.AmbientButton_2.Value = true;
            app.ElevatedButton_2 = uiradiobutton(app.TemperatureChangeCButtonGroup);
            app.ElevatedButton_2.Text = 'Elevated';
            app.NumbersEditField_4 = uieditfield(app.TemperatureChangeCButtonGroup, 'numeric');
            app.NumbersEditField_4.Value = 1;
            app.text5 = uilabel(app.CANOPYPROCESSTab);
            app.text5.Tag = 'text3';
            app.text5.Text = {'Select multi-species or intended single species'; '    - Select multi-species simulation or'; '    - Select any one of the multi-species for single species simulation'; ''; ''; ''; 'Select the shape of water retention curve. van Genuchten (vanGen) or Brooks'; 'and Corey (Brooks)'; '                                                                                               '; ''; ''; 'Select Linear decrease or No change of root hydraulic conductivity with soil '; 'depth'; ''; ''; 'Select atmospheric Carbon dioxide (CO2) concentration:'; '    - Ambient: Current CO2 concentration'; '    - Elevated: CO2 concentration is increased'; ''; ''; 'Select Temperature change:'; '    - Ambient: Current temperature'; '    - Elevated: Temperature is increased'};
            app.YEARWISEDETAILSTab = uitab(app.TabGroup);
            app.YEARWISEDETAILSTab.Title = 'YEAR-WISE DETAILS';
            app.StartYearEditFieldLabel = uilabel(app.YEARWISEDETAILSTab);
            app.StartYearEditFieldLabel.Text = 'Start Year';
            app.StartYearEditField = uieditfield(app.YEARWISEDETAILSTab, 'numeric');
            app.StartYearEditField.Value = 2009;
            app.EndYearEditFieldLabel = uilabel(app.YEARWISEDETAILSTab);
            app.EndYearEditFieldLabel.Text = 'End Year';
            app.EndYearEditField = uieditfield(app.YEARWISEDETAILSTab, 'numeric');
            app.EndYearEditField.Value = 2012;
            app.FillYearsButton = uibutton(app.YEARWISEDETAILSTab, 'push');
            app.FillYearsButton.ButtonPushedFcn = createCallbackFcn(app, @FillYearsButtonPushed, true);
            app.FillYearsButton.Text = 'Fill Years';
            app.SelectYearsLabel = uilabel(app.YEARWISEDETAILSTab);
            app.SelectYearsLabel.Text = 'Select Years';
            app.YearsList = uidropdown(app.YEARWISEDETAILSTab);
            app.YearsList.Items = {'Years'};
            app.YearsList.ValueChangedFcn = createCallbackFcn(app, @YearsListValueChanged, true);
            app.YearsList.Value = 'Years';
            app.SpeciesDropDownLabel = uilabel(app.YEARWISEDETAILSTab);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.YEARWISEDETAILSTab);
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SaveButton = uibutton(app.YEARWISEDETAILSTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavedLamp = uilamp(app.YEARWISEDETAILSTab);
            app.SavedLamp.Tooltip = {'Indicate if the file is saved.'};
            app.GrDetails = uitabgroup(app.YEARWISEDETAILSTab);
            app.GrDetails.SelectionChangedFcn = createCallbackFcn(app, @GrDetailsSelectionChanged, true);
            app.VegetationDetailsTab = uitab(app.GrDetails);
            app.VegetationDetailsTab.Title = 'Vegetation Details';
            app.VegetationSwitchLabel = uilabel(app.VegetationDetailsTab);
            app.VegetationSwitchLabel.Text = 'Vegetation';
            app.VegetationSwitch = uiswitch(app.VegetationDetailsTab, 'slider');
            app.VegetationSwitch.Items = {'No', 'Yes'};
            app.VegetationSwitch.ValueChangedFcn = createCallbackFcn(app, @VegetationSwitchValueChanged, true);
            app.VegetationSwitch.Value = 'No';
            app.Photosynthesis = uibuttongroup(app.VegetationDetailsTab);
            app.PhotosynthesisLabel = uilabel(app.Photosynthesis);
            app.PhotosynthesisLabel.Text = 'Photosynthesis';
            app.C3Button = uiradiobutton(app.Photosynthesis);
            app.C3Button.Text = 'C3';
            app.C3Button.Value = true;
            app.C4Button = uiradiobutton(app.Photosynthesis);
            app.C4Button.Text = 'C4';
            app.VegetationNameEditFieldLabel = uilabel(app.VegetationDetailsTab);
            app.VegetationNameEditFieldLabel.Text = 'Vegetation Name';
            app.VegetationNameEditField = uieditfield(app.VegetationDetailsTab, 'text');
            app.CanopyStructurePanel = uipanel(app.VegetationDetailsTab);
            app.CanopyStructurePanel.Title = 'Canopy Structure';
            app.CanopyLayersEditFieldLabel = uilabel(app.CanopyStructurePanel);
            app.CanopyLayersEditFieldLabel.Text = '# Canopy Layers';
            app.CanopyLayersEditField = uieditfield(app.CanopyStructurePanel, 'numeric');
            app.CanopyLayersEditField.Value = 20;
            app.AddEditButton = uibutton(app.CanopyStructurePanel, 'push');
            app.AddEditButton.ButtonPushedFcn = createCallbackFcn(app, @AddEditButtonPushed, true);
            app.AddEditButton.Text = 'Add/Edit';
            app.Load = uibutton(app.CanopyStructurePanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.RootStructurePanel = uipanel(app.VegetationDetailsTab);
            app.RootStructurePanel.Title = 'Root Structure';
            app.RootLayersEditFieldLabel = uilabel(app.RootStructurePanel);
            app.RootLayersEditFieldLabel.Text = '# Root Layers';
            app.RootLayersEditField = uieditfield(app.RootStructurePanel, 'numeric');
            app.RootLayersEditField.Value = 12;
            app.AddEditButton_2 = uibutton(app.RootStructurePanel, 'push');
            app.AddEditButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddEditButton_2Pushed, true);
            app.AddEditButton_2.Text = 'Add/Edit';
            app.Load_2 = uibutton(app.RootStructurePanel, 'push');
            app.Load_2.ButtonPushedFcn = createCallbackFcn(app, @Load_2ButtonPushed, true);
            app.Load_2.Text = 'Load';
            app.Plot_CStructure = uiaxes(app.VegetationDetailsTab);
            title(app.Plot_CStructure, '')
            xlabel(app.Plot_CStructure, 'X')
            ylabel(app.Plot_CStructure, 'Y')
            app.Plot_RStructure = uiaxes(app.VegetationDetailsTab);
            title(app.Plot_RStructure, '')
            xlabel(app.Plot_RStructure, 'X')
            ylabel(app.Plot_RStructure, 'Y')
            app.Plot_RStructure.YDir = 'reverse';
            app.ManagementPracticesTab = uitab(app.GrDetails);
            app.ManagementPracticesTab.Title = 'Management Practices';
            app.PlantingDOYEditFieldLabel = uilabel(app.ManagementPracticesTab);
            app.PlantingDOYEditFieldLabel.Text = 'Planting DOY';
            app.PlantingDOYEditField = uieditfield(app.ManagementPracticesTab, 'numeric');
            app.PlantingDOYEditField.Value = 1;
            app.HarvestDOYEditFieldLabel = uilabel(app.ManagementPracticesTab);
            app.HarvestDOYEditFieldLabel.Text = 'Harvest DOY';
            app.HarvestDOYEditField = uieditfield(app.ManagementPracticesTab, 'numeric');
            app.HarvestDOYEditField.Value = 365;
            app.IrrigatedCheckBox = uicheckbox(app.ManagementPracticesTab);
            app.IrrigatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @IrrigatedCheckBoxValueChanged, true);
            app.IrrigatedCheckBox.Text = 'Irrigated';
            app.MethodofIrrigationListBox = uidropdown(app.ManagementPracticesTab);
            app.MethodofIrrigationListBox.Items = {'Manually', 'LWP based', 'CWSI based', 'ET based'};
            app.MethodofIrrigationListBox.ValueChangedFcn = createCallbackFcn(app, @MethodofIrrigationListBoxValueChanged, true);
            app.MethodofIrrigationListBox.Value = 'Manually';
            app.FertilizersAppliedCheckBox = uicheckbox(app.ManagementPracticesTab);
            app.FertilizersAppliedCheckBox.ValueChangedFcn = createCallbackFcn(app, @FertilizersAppliedCheckBoxValueChanged, true);
            app.FertilizersAppliedCheckBox.Text = 'Fertilizers Applied';
            app.MethodofFertilizersListBox = uidropdown(app.ManagementPracticesTab);
            app.MethodofFertilizersListBox.Items = {'Manually', 'Based on GS', 'With Irrigation'};
            app.MethodofFertilizersListBox.ValueChangedFcn = createCallbackFcn(app, @MethodofFertilizersListBoxValueChanged, true);
            app.MethodofFertilizersListBox.Value = 'Manually';
            app.AddIrrigationButton = uibutton(app.ManagementPracticesTab, 'push');
            app.AddIrrigationButton.ButtonPushedFcn = createCallbackFcn(app, @AddIrrigationButtonPushed, true);
            app.AddIrrigationButton.Text = 'Add Irrigation';
            app.DelIrrigationButton = uibutton(app.ManagementPracticesTab, 'push');
            app.DelIrrigationButton.ButtonPushedFcn = createCallbackFcn(app, @DelIrrigationButtonPushed, true);
            app.DelIrrigationButton.Text = 'Del Irrigation';
            app.IrrigationschedulesLabel = uilabel(app.ManagementPracticesTab);
            app.IrrigationschedulesLabel.Text = 'Irrigation schedules';
            app.IrrTable = uitable(app.ManagementPracticesTab);
            app.IrrTable.ColumnName = {'DOY'; 'Irrigated water(mm)'};
            app.IrrTable.RowName = {};
            app.IrrTable.ColumnSortable = [true false];
            app.IrrTable.ColumnEditable = true;
            app.IrrTable.CellSelectionCallback = createCallbackFcn(app, @IrrTableCellSelection, true);
            app.IrrTable.Tooltip = {'Add values'};
            app.AddFertilizerButton = uibutton(app.ManagementPracticesTab, 'push');
            app.AddFertilizerButton.ButtonPushedFcn = createCallbackFcn(app, @AddFertilizerButtonPushed, true);
            app.AddFertilizerButton.Text = 'Add Fertilizer';
            app.DelFertilizerButton = uibutton(app.ManagementPracticesTab, 'push');
            app.DelFertilizerButton.ButtonPushedFcn = createCallbackFcn(app, @DelFertilizerButtonPushed, true);
            app.DelFertilizerButton.Text = 'Del Fertilizer';
            app.NitrogenFertilizersschedulesLabel = uilabel(app.ManagementPracticesTab);
            app.NitrogenFertilizersschedulesLabel.Text = 'Nitrogen Fertilizers schedules';
            app.NitTable = uitable(app.ManagementPracticesTab);
            app.NitTable.ColumnName = {'DOY'; 'Ammonium'; 'Nitrate'; 'Urea'};
            app.NitTable.RowName = {};
            app.NitTable.ColumnSortable = [true false false false];
            app.NitTable.ColumnEditable = true;
            app.NitTable.CellSelectionCallback = createCallbackFcn(app, @NitTableCellSelection, true);
            app.SOILCARBONANDNITROGENPROCESSTab = uitab(app.TabGroup);
            app.SOILCARBONANDNITROGENPROCESSTab.Title = 'SOIL CARBON AND NITROGEN PROCESS';
            app.SoilOrganicCarbonButtonGroup = uibuttongroup(app.SOILCARBONANDNITROGENPROCESSTab);
            app.SoilOrganicCarbonButtonGroup.Title = 'Soil Organic Carbon';
            app.PoolButton = uiradiobutton(app.SoilOrganicCarbonButtonGroup);
            app.PoolButton.Text = '1 Pool';
            app.PoolButton.Value = true;
            app.PoolsButton = uiradiobutton(app.SoilOrganicCarbonButtonGroup);
            app.PoolsButton.Text = '2 Pools';
            app.NUptakeButtonGroup = uibuttongroup(app.SOILCARBONANDNITROGENPROCESSTab);
            app.NUptakeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NUptakeButtonGroupSelectionChanged, true);
            app.NUptakeButtonGroup.Title = 'N Uptake';
            app.RB_table = uitable(app.NUptakeButtonGroup);
            app.RB_table.ColumnName = {'Parameters'; 'Order 1'; 'Order 2'; 'Order 3'; 'Order 4'};
            app.RB_table.RowName = {};
            app.RB_table.ColumnEditable = [false true true true true];
            app.RB_table.CellEditCallback = createCallbackFcn(app, @RB_tableCellEdit, true);
            app.RB_table.CellSelectionCallback = createCallbackFcn(app, @RB_tableCellSelection, true);
            app.RB_table.DisplayDataChangedFcn = createCallbackFcn(app, @RB_tableDisplayDataChanged, true);
            app.PlantNdemandButton = uiradiobutton(app.NUptakeButtonGroup);
            app.PlantNdemandButton.Text = 'Plant N demand';
            app.NitrogenFixationCheckBox = uicheckbox(app.NUptakeButtonGroup);
            app.NitrogenFixationCheckBox.Text = 'Nitrogen Fixation';
            app.NitrogenRemobilizationCheckBox = uicheckbox(app.NUptakeButtonGroup);
            app.NitrogenRemobilizationCheckBox.Text = 'Nitrogen Remobilization';
            app.RootBiomassButton = uiradiobutton(app.NUptakeButtonGroup);
            app.RootBiomassButton.Text = 'Root Biomass';
            app.RootBiomassButton.Value = true;
            app.text6 = uilabel(app.SOILCARBONANDNITROGENPROCESSTab);
            app.text6.Tag = 'text3';
            app.text6.Text = {'Select soil organic carbon pool that applies each soil layer'; '  - 1 Pool: A single compartment of soil carbon subtrate '; '  - 2 Pools: Litter and humus pool'; ''; ''; 'Select plant nitrogen uptake process'; '  - Root Biomass: With input of root biomass dynamic, computing nitrogen '; '    uptake process'; '  - Plant N Demand: With input of plant nitrogen demand, computing nitrogen '; '    uptake process'; ''; 'Insert the parameters to simulate nitrogen uptake processes for root biomass '; 'N uptake case'; '  -disam: Root diameter [m]; '; '  -dist: Root distribution [mm];'; '  -SRL: Specific root length [m/g]'; ''; ''; ''; 'Check boxes to include/exclude sub-models in soil carbon and nitrogen process'; '  - Nitrogen Fixation: Simulating plant nitrogen fixation from atmosphere'; '  - Nitrogen Remobilization: Simulating plant nitrogen remobilization from '; '    previous year''s nitrogen uptake'; ''};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_option_App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end