classdef model_parameters_App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        text3                         matlab.ui.control.Label
        text2                         matlab.ui.control.Label
        SpeciesDropDownLabel          matlab.ui.control.Label
        SpeciesDropDown               matlab.ui.control.DropDown
        PROCESSES                     matlab.ui.container.TabGroup
        LEAFCANOPYTab                 matlab.ui.container.Tab
        LeafTable                     matlab.ui.control.Table
        LeafTable_2                   matlab.ui.control.Table
        LeafLabel                     matlab.ui.control.Label
        CanopyLabel                   matlab.ui.control.Label
        CanopyTable                   matlab.ui.control.Table
        CanopyTable_2                 matlab.ui.control.Table
        MICROENVIRONMENTTab           matlab.ui.container.Tab
        MicroenvironTable             matlab.ui.control.Table
        MicroenvironmentLabel         matlab.ui.control.Label
        RadiationTable                matlab.ui.control.Table
        RadiationLabel                matlab.ui.control.Label
        SoilTable                     matlab.ui.control.Table
        SoilLabel                     matlab.ui.control.Label
        ECOPHYSIOLOGYTab              matlab.ui.container.Tab
        ECOsubTab                     matlab.ui.container.TabGroup
        PHOTOSYNTHESISRESPIRATIONTab  matlab.ui.container.Tab
        RespirationTable              matlab.ui.control.Table
        RespirationLabel              matlab.ui.control.Label
        C3PhotoTable                  matlab.ui.control.Table
        C3typeLabel                   matlab.ui.control.Label
        C4PhotoTable                  matlab.ui.control.Table
        C4typeLabel                   matlab.ui.control.Label
        CONDUCTANCETab                matlab.ui.container.Tab
        ConductanceTable              matlab.ui.control.Table
        ConductanceLabel              matlab.ui.control.Label
        CROPGROWTHTab                 matlab.ui.container.Tab
        CGMFTable                     matlab.ui.control.Table
        CarbohydratetoLeafStemRootandGrainLabel  matlab.ui.control.Label
        CGM1Table                     matlab.ui.control.Table
        CropgrowthparametersLabel     matlab.ui.control.Label
        NUTRIENTTab                   matlab.ui.container.Tab
        NutrientSiteTable             matlab.ui.control.Table
        NutrientSiteSpecificLabel     matlab.ui.control.Label
        NutrientSpeciesTable          matlab.ui.control.Table
        NutrientSpeciesSpecificLabel  matlab.ui.control.Label
        NutrientSpeciesTable_2        matlab.ui.control.Table
        InputdecompositionparametersLabel  matlab.ui.control.Label
        DecomAddEditButton            matlab.ui.control.Button
        NALLOCATIONTab                matlab.ui.container.Tab
        NallocationTable              matlab.ui.control.Table
        NallocationparametersLabel    matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        SavedLamp                     matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;
            
            Vi=size(dir([Config_folder, '/Par/*.mat']));
            Vs=Vi(1);
            
            if Vs>=1
                answer = questdlg('Do you want to delete old parameters files from the folder and create new files?', ...
                    'Create new files', ...
                    'No','Yes', 'No');
                switch answer
                    case 'Yes'
                        delete([Config_folder, '/Par/*.mat'])
                end
            end
            
            
            load ('./Temps/temp_model.mat', 'num_species', 'ParFilesList')
            
            Species = compose('%g', 1:num_species);
            
            app.SpeciesDropDown.Items=Species;
            
            app.SpeciesDropDown.Value='1';
            
            filename_P= ['ParSpec_', num2str(1), '.mat'];
            
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;
            
            fullpath_P= fullfile(Config_folder, 'Par', filename_P);
            

            if ~isfile(fullpath_P)
                
                app.SavedLamp.Color='red';
                
                %% PARAMETERS
                %Empty information used for parameters.
                emp_cell = zeros(-1);
                
                % Site specific parameter
                para_radiation = {...
                    ' Atmospheric transmissivity'                                           , emp_cell  , 0.65  , '          unitless';...
                    ' Vegetation emissivity'                                                , emp_cell  , 0.97  , '          unitless';...
                    ' Soil emissivity'                                                      , emp_cell  , 0.95  , '          unitless';...
                    ' Atmospheric emissivity'                                               , emp_cell  , 0.80  , '          unitless';...
                    ' Leaf angle distribution parameter'                                    , emp_cell  , 1.0   , '          unitless';...
                    ' Leaf clumping parameter'                                              , emp_cell  , 0.9   , '          unitless';...
                    ' Extinction coefficient for diffuse'                                   , emp_cell  , 0.7   , '          unitless';...
                    ' Leaf absorptivity to Photosynthetically Active Radiation (PAR)'       , emp_cell  , 0.85  , '          unitless';...
                    ' Leaf absorptivity to Near Infrared Radiation (NIR)'                   , emp_cell  , 0.45  , '          unitless';...
                    ' PAR reflection coefficient'                                           , emp_cell  , 0.05  , '          unitless';...
                    ' NIR reflection coefficient'                                           , emp_cell  , 0.2   , '          unitless';...
                    ' Soil reflection coefficient'                                          , emp_cell  , 0.17  , '          unitless';...
                    ' New snow reflection coefficient'                                      , emp_cell  , 0.8   , '          unitless';...
                    ' How much reflectivity of snow decreases with age (per 12 days)'       , emp_cell  , 0.1   , '          unitless';...
                    };
                
                para_microenvironment = {...
                    ' Drag coefficient, Cd'                                                 , emp_cell  , 0.2   , '          unitless';  ...
                    };
                
                para_soil = {...
                    ' Percent of sand'                                                      , emp_cell  , 10    , '          unitless';...
                    ' Percent of clay'                                                      , emp_cell  , 80    , '          unitless';...
                    ' Soil drag coefficient'                                                , emp_cell  , 0.10  , '          unitless';...
                    ' Soil surface roughness length'                                        , emp_cell  , 0.005 , '              m   ';...
                    };
                
                para_respiration = {...
                    ' Respiration Ro'                                                       , emp_cell  , 2.0   , '   mol / (sq m. s)'; ...
                    ' Respiration Q10'                                                      , emp_cell  , 1.5   , '   mol / (sq m. s)'; ...
                    };
                
                para_soilCN = {...
                    ' Carbon to nitrogen ratio of microbial biomass'                        , emp_cell  , 11.8  , '          unitless'; ...
                    ' Rate of nitrification'                                                , emp_cell  , 0.0001, '   cu m. / (d. gN)'; ...
                    ' Respiration coefficient'                                              , emp_cell  , 0.6   , '          unitless'; ...
                    ' Ammonium immobilization coefficient'                                  , emp_cell  , 1.0   , '   cu m. / (d. gN)'; ...
                    ' Nitrate immobilization coefficient'                                   , emp_cell  , 1.0   , '   cu m. / (d. gN)'; ...
                    ' Maximum ammonium immobilization coefficient'                          , emp_cell  , 0.1   , '   cu m. / (d. gN)'; ...
                    ' Maximum nitrate immobilization coefficient'                           , emp_cell  , 0.1   , '   cu m. / (d. gN)'; ...
                    ' Fraction of dissolved ammonium'                                       , emp_cell  , 0.05  , '          unitless'; ...
                    ' Fraction of dissolved nitrate'                                        , emp_cell  , 1.0   , '          unitless'; ...
                    ' Bioturbation diffusion coefficient at soil surface'                   , emp_cell  ,      4, '        sq cm / yr'; ...
                    ' Litter fragmentation'                                                 , emp_cell  ,0.0000215, '             / d'; ...
                    ' Fraction of nitrification lost as N2O flux - denitrification'         , emp_cell  , 0.02  , '          unitless'; ...
                    ' Carbon to nitrogen ratio of humus - 2 pools'                          , emp_cell  , 22    , '          unitless'; ...
                    ' Minimum fraction of the decompoased litter that undergoes humification - 2 pools' , emp_cell, 0.15  , '          unitless'; ...
                    ' Volatilization fraction of urea of applied fertilizer'                , emp_cell  , 0.205 , '          unitless'; ...
                    ' Volatilization fraction of ammonium of applied fertilizer'            , emp_cell  , 0.61  , '          unitless'; ...
                    ' Definition zone of disturbance - N uptake with root biomass'          , emp_cell  , 0.1   , '             mm   '; ...
                    ' Maximum root nitrate uptake - N uptake with root biomass'             , emp_cell  , 0.5   , '   mmolNO3 / g /hr'; ...
                    ' Michaelis nitrate constant - N uptake with root biomass'              , emp_cell  , 72    , '    mmolNO3 / cu m'; ...
                    ' Maximum root ammonium uptake - N uptake with root biomass'            , emp_cell  , 0.053 , '   mmolNH4 / g /hr'; ...
                    ' Michaelis ammonium constant - N uptake with root biomass'             , emp_cell  , 15    , '    mmolNh4 / cu m'; ...
                    };
                
                % Each species specific parameter
                para_soilCN_crop = {...
                    ' Constant ratio of ''natural litter input'' to ''belowground litter input'' ' , emp_cell   , 0.8 , '    unitless'; ...
                    };
                para_soilCN_crop_s = {...
                    ' Fraction of ammonium demand from total N demand'                      , emp_cell  , 0.0   , '          unitless'; ...
                    ' Fraction of nitrate demand from total N demand'                       , emp_cell  , 1.0   , '          unitless'; ...
                    ' Tortuosity factor in active N uptake'                                 , emp_cell  , 3.0   , '          unitless'; ...
                    ' Rescaled diffusion coefficient'                                       , emp_cell  , 0.06  , '          m / cu m'; ...
                    ' Nitrgeon fixation index (plant N from fixation / plant N from soil)'  , emp_cell  , 2.0   , '          unitless'; ...
                    ' Nitrgeon remobilization index (fraction of previous year''s N uptake)', emp_cell  , 0.6   , '          unitless'; ...
                    };
                
                %
                para_leaf_crop = {...
                    ' Latent Heat production from 1 or both sides of leaf'                  , emp_cell  , 1     , '          unitless';...
                    ' Sensible Heat production from 1 or both sides of leaf'                , emp_cell  , 1     , '          unitless';...
                    ' Longwave production from 1 or both sides of leaf'                     , emp_cell  , 2     , '          unitless';...
                    ' Leaf Type: 1-Broad leaf or 2-Needle leaf'                             , emp_cell  , 1     , '          unitless';...
                    };
                para_leaf_crop_s = {...
                    ' Maximum h2o storage capacity for foliage'                             , emp_cell  , 0.2     , '     mm / LAI unit';...
                    };
                
                %%
                para_canopy_crop = {...
                    ' Leaf width or needle diameter'                                        , emp_cell  , 0.05  , '              m   ';...
                    ' Shoot diameter for conifers or leaf width for broad leaf'             , emp_cell  , 0.05  , '              m   ';...
                    };
                
                para_canopy_crop_fixed = {...
                    ' Maximum wetted fraction'                                              , emp_cell  , 0.2   , '          unitless';...
                    ' Rainfall interception factor'                                         , emp_cell  , 0.2   , '          unitless';...
                    ' Canopy height'                                                        , emp_cell  , 40    , '              m   ';...
                    ' Flux tower observation height'                                        , emp_cell  , 64    , '              m   ';...
                    ' Canopy roughness length'                                              , emp_cell  , 5.2   , '              m   ';...
                    };
                
                %%
                para_photosynthesisC4_crop = {...
                    ' Maximum Rubisco capacity Vmax'                                        , emp_cell  , 45    , '   mol / (sq m. s)'; ...
                    ' Leaf respiration Rd'                                                  , emp_cell  , 0.8   , '   mol / (sq m. s)'; ...
                    ' Temperature sensitivity Q10'                                          , emp_cell  , 0.57  , '          unitless'; ...
                    ' Initial slope of CO2 response'                                        , emp_cell  , 0.7   , '   mol / (sq m. s)'; ...
                    ' Curvature factor theta'                                               , emp_cell  , 0.83  , '          unitless'; ...
                    ' Curvature factor beta'                                                , emp_cell  , 0.93  , '          unitless'; ...
                    ' Intrisic quantum yield of C4 photosynthesis - alpha'                  , emp_cell  , 0.04  , '          unitless'; ...
                    ' Vertical distribution of Photosynthetic capacity'                     , emp_cell  , 0.4   , '          unitless'; ...
                    };
                
                para_photosynthesisC3_crop = {...
                    ' Fraction absorbed Q available to photosystem II'                      , emp_cell  , 0.5   , '          unitless'; ...
                    ' Maximum rate of Rubisco-limited carbonxylation at 25 C - Vcmax'       , emp_cell  , 31    , '  umol / (sq m. s)'; ...
                    ' Maximum electron transport rate - Jmax'                               , emp_cell  , 37    , '  umol / (sq m. s)'; ...
                    ' Rd25'                                                                 , emp_cell  , 0.3   , '  umol / (sq m. s)'; ...
                    ' Vertical distribution of Photosynthetic capacity'                     , emp_cell  , 0.4   , '          unitless'; ...
                    };
                
                %%
                para_conductance_crop = {...
                    ' Slope parameter in Ball-Berry model, m'                               , emp_cell  , 12    , '          unitless'; ...
                    ' Intercepts in Ball Berry model, b'                                    , emp_cell  , 0.008 , '   mol / (sq m. s)'; ...
                    ' Sensitivity parameter for initial decrease in leaf potential'         , emp_cell  , 3.2   , '         unitless '; ...
                    ' Leaf potential at which half of the hydraulic conductance is lost'    , emp_cell  , -3    , '              MPa '; ...
                    ' Radial conductivity of the root system'                               , emp_cell  ,4.66e-9, '               /s '; ...
                    ' Axial specific conductivity of the root system'                       , emp_cell  ,4.5e-5 , '             mm/s '; ...
                    ' Parameter, kpar_ax'                                                   , emp_cell  ,0.00036, '          unitless'; ...
                    ' Parameter, Rp'                                                        , emp_cell  , 8     , '          unitless'; ...
                    };
                
                
                para_N_allocation = {...
                    ' Fraction of leaf structural protein'                               , emp_cell  , 0.31    , 'unitless'; ...
                    ' Fraction of thylakoid nitrogen that is allocated to light harvesting pool',...
                    emp_cell  , 0.80 , '   unitless)'; ...
                    ' Nitrogen to chlorophyll ratio of pigment protein complexes'         , emp_cell  , 38.5   , 'mol N (mol Chl)-1 '; ...
                    ' Min Light extinction coefficient'                                         , emp_cell  , 0.5    , 'unitless '; ...
                    ' Max Light extinction coefficient'                                         , emp_cell  , 1.8    , 'unitless '; ...
                    ' Min Fraction of leaf nitrogen allocated into thylakoid pool'              , emp_cell  , 0.20  , 'unitless '; ...
                    ' Max Fraction of leaf nitrogen allocated into thylakoid pool'              , emp_cell  , 0.40  , 'unitless '; ...
                    ' Min Leaf nitrogen allocated to rubisco pool'                              , emp_cell  , 0.06 , 'unitless';...
                    ' Max Leaf nitrogen allocated to rubisco pool'                              , emp_cell  , 0.20 , 'unitless';...
                    };
                
                para_CGM1 = {...
                    ' Base temperature for GDD accumulation', emp_cell, 10, '`C'; ...
                    ' Cut-off temperature for GDD accumulation', emp_cell, 30, '`C'; ...
                    ' GDD from seeding to emergence', emp_cell, 47, 'Degree Days'; ...
                    ' GDD from seeding to initial vegetative end', emp_cell, 591, 'Degree Days'; ...
                    ' GDD from seeding to normal vegetative end', emp_cell, 732, 'Degree Days'; ...
                    ' GDD from seeding to initial reproductive end', emp_cell, 904, 'Degree Days'; ...
                    ' GDD from seeding to normal reproductive end', emp_cell, 1482, 'Degree Days'; ...
                    ' Q10 for maintenance respiration', emp_cell, 2.0, 'unitless'; ...
                    ' Specific leaf area', emp_cell, 0.015, 'unitless'; ...
                    'Leaf maintenance respiration at 25`C', emp_cell, 1.0, 'μmol CO2/m2/s'; ...
                    'Stem maintenance respiration at 25`C', emp_cell, 0.05, 'μmol CO2/m2/s'; ...
                    'Root maintenance respiration at 25`C', emp_cell, 0.05, 'μmol CO2/m2/s'; ...
                    'Grain maintenance respiration at 25`C', emp_cell, 0.01, 'μmol CO2/m2/s'; ...
                    'Fraction of growth respiration', emp_cell, 0.20, 'unitless'; ...
                    'Leaf turnover coefficient at initial reproductive', emp_cell, 0.20, '1/s'; ...
                    'Leaf turnover coefficient at normal reproductive', emp_cell, 0.30, '1/s'; ...
                    'Stem turnover coefficient at initial reproductive', emp_cell, 0.12, '1/s'; ...
                    'Stem turnover coefficient at normal reproductive', emp_cell, 0.06, '1/s'; ...
                    'Root turnover coefficient at initial reproductive', emp_cell, 0.12, '1/s'; ...
                    'Root turnover coefficient at normal reproductive', emp_cell, 0.06, '1/s'; ...
                    'Leaf carbon fraction', emp_cell, 0.416, 'unitless'; ...
                    'Stem carbon fraction', emp_cell, 0.458, 'unitless'; ...
                    'Root carbon fraction', emp_cell, 0.343, 'unitless'; ...
                    'Grain carbon fraction', emp_cell, 0.400, 'unitless'; ...
                    'Harvest Index', emp_cell, 0.65, 'unitless'; ...
                    'Foliage nitrogen concentration', emp_cell, 1.5, '%';...
                    'Characteristic T for leaf freezing', emp_cell, 268, 'K';...
                    'Coefficient for leaf temperature stress death at initial reproductive', emp_cell, 0.05, '1/s';...
                    'Coefficient for leaf temperature stress death at normal reproductive', emp_cell, 0.05, '1/s';...
                    
                    };
                
                
                para_CGMF = {...
                    'Fraction of carbohydrate flux to LEAF at initial vegetative', emp_cell, 0.50, 'unitless'; ...
                    'Fraction of carbohydrate flux to LEAF at normal vegetative', emp_cell, 0.32, 'unitless'; ...
                    'Fraction of carbohydrate flux to LEAF at initial productive', emp_cell, 0.18, 'unitless'; ...
                    'Fraction of carbohydrate flux to LEAF at normal productive', emp_cell, 0.08, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at initial vegetative', emp_cell, 0.25, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at normal vegetative', emp_cell, 0.38, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at initial productive', emp_cell, 0.25, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at normal productive', emp_cell, 0.17, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at initial vegetative', emp_cell, 0.25, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at normal vegetative', emp_cell, 0.30, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at initial productive', emp_cell, 0.17, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at normal productive', emp_cell, 0.10, 'unitless'; ...
                    'Fraction of carbohydrate flux to GRAIN at initial productive', emp_cell, 0.40, 'unitless'; ...
                    'Fraction of carbohydrate flux to GRAIN at normal productive', emp_cell, 0.65, 'unitless'; ...
                    };
                
                current_species = 1;
                
            else
                
                load (fullpath_P, ...
                    ...%Parameters
                    'para_leaf_crop', 'para_leaf_crop_s',...
                    'para_canopy_crop', 'para_canopy_crop_fixed', ...
                    ... % MicroEnvironment
                    'para_microenvironment', 'para_radiation', 'para_soil',...
                    ... % Ecophysiology
                    'para_photosynthesisC3_crop', 'para_photosynthesisC4_crop', ...
                    'para_respiration', 'para_conductance_crop', ...
                    ... % CropGrowth
                    'para_CGM1', 'para_CGMF',...
                    ... % Nutrient
                    'para_soilCN', 'para_soilCN_crop', 'para_soilCN_crop_s',...
                    ... % N-Allocation
                    'para_N_allocation',...
                    ... %Others
                    'current_species')
                
                app.SavedLamp.Color='green';
                
            end
                        
%             load    './Temps/temporary.mat' 'working_name';
            
            load (  './Temps/temp_model.mat',...
                'CGM', 'Soil_nutrient', 'NCanAlMod')
            
            app.LeafTable.Data=para_leaf_crop;
            app.LeafTable_2.Data=para_leaf_crop_s;
            app.CanopyTable.Data=para_canopy_crop;
            app.CanopyTable_2.Data=para_canopy_crop_fixed;
            
            app.MicroenvironTable.Data=para_microenvironment;
            app.RadiationTable.Data=para_radiation;
            app.SoilTable.Data=para_soil;
            
            app.C3PhotoTable.Data=para_photosynthesisC3_crop;
            app.C4PhotoTable.Data=para_photosynthesisC4_crop;
            app.RespirationTable.Data=para_respiration;
            app.ConductanceTable.Data=para_conductance_crop;
            
            app.NutrientSiteTable.Data=para_soilCN;
            app.NutrientSpeciesTable.Data=para_soilCN_crop;
            app.NutrientSpeciesTable_2.Data=para_soilCN_crop_s;
            
            app.NallocationTable.Data=para_N_allocation;
            
            app.CGMFTable.Data=para_CGMF;
            app.CGM1Table.Data=para_CGM1;
            
            
            if Soil_nutrient==1
                app.NUTRIENTTab.Parent=app.PROCESSES;
            else
                app.NUTRIENTTab.Parent=[];
            end
            
            if NCanAlMod==1
                app.NALLOCATIONTab.Parent=app.PROCESSES;
            else
                app.NALLOCATIONTab.Parent=[];
            end
            
            if CGM==1
                app.CROPGROWTHTab.Parent=app.PROCESSES;
            else
                app.CROPGROWTHTab.Parent=[];
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            load('./Temps/temp_model.mat', "num_species")
                        
            load('.\Temps\temporary.mat', 'config_path');
            
            Config_folder=config_path;
            
            Vi=size(dir([Config_folder, '/Par/*.mat']));
            Vs=Vi(1);
%             Ys=size(ParFilesList);
            
            if Vs ~=num_species
                uiwait(msgbox('Please save parameters files for all species'));
                return
            end
            
            closereq
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            
            load('./Temps/temp_model.mat', 'ParFilesList')
            
            ii=app.SpeciesDropDown.Value;
            
            load('.\Temps\temporary.mat', 'config_path');
            
            filename_P= ['ParSpec_', ii, '.mat'];
            fullpath_P= fullfile(config_path, 'Par', filename_P);

            if ~isfile(fullpath_P)
                
                app.SavedLamp.Color='red';
                
                %% PARAMETERS
                %Empty information used for parameters.
                emp_cell = zeros(-1);
                
                % Site specific parameter
                para_radiation = {...
                    ' Atmospheric transmissivity'                                           , emp_cell  , 0.65  , '          unitless';...
                    ' Vegetation emissivity'                                                , emp_cell  , 0.97  , '          unitless';...
                    ' Soil emissivity'                                                      , emp_cell  , 0.95  , '          unitless';...
                    ' Atmospheric emissivity'                                               , emp_cell  , 0.80  , '          unitless';...
                    ' Leaf angle distribution parameter'                                    , emp_cell  , 1.0   , '          unitless';...
                    ' Leaf clumping parameter'                                              , emp_cell  , 0.9   , '          unitless';...
                    ' Extinction coefficient for diffuse'                                   , emp_cell  , 0.7   , '          unitless';...
                    ' Leaf absorptivity to Photosynthetically Active Radiation (PAR)'       , emp_cell  , 0.85  , '          unitless';...
                    ' Leaf absorptivity to Near Infrared Radiation (NIR)'                   , emp_cell  , 0.45  , '          unitless';...
                    ' PAR reflection coefficient'                                           , emp_cell  , 0.05  , '          unitless';...
                    ' NIR reflection coefficient'                                           , emp_cell  , 0.2   , '          unitless';...
                    ' Soil reflection coefficient'                                          , emp_cell  , 0.17  , '          unitless';...
                    ' New snow reflection coefficient'                                      , emp_cell  , 0.8   , '          unitless';...
                    ' How much reflectivity of snow decreases with age (per 12 days)'       , emp_cell  , 0.1   , '          unitless';...
                    };
                
                para_microenvironment = {...
                    ' Drag coefficient, Cd'                                                 , emp_cell  , 0.2   , '          unitless';  ...
                    };
                
                para_soil = {...
                    ' Percent of sand'                                                      , emp_cell  , 10    , '          unitless';...
                    ' Percent of clay'                                                      , emp_cell  , 80    , '          unitless';...
                    ' Soil drag coefficient'                                                , emp_cell  , 0.10  , '          unitless';...
                    ' Soil surface roughness length'                                        , emp_cell  , 0.005 , '              m   ';...
                    };
                
                para_respiration = {...
                    ' Respiration Ro'                                                       , emp_cell  , 2.0   , '   mol / (sq m. s)'; ...
                    ' Respiration Q10'                                                      , emp_cell  , 1.5   , '   mol / (sq m. s)'; ...
                    };
                
                para_soilCN = {...
                    ' Carbon to nitrogen ratio of microbial biomass'                        , emp_cell  , 11.8  , '          unitless'; ...
                    ' Rate of nitrification'                                                , emp_cell  , 0.0001, '   cu m. / (d. gN)'; ...
                    ' Respiration coefficient'                                              , emp_cell  , 0.6   , '          unitless'; ...
                    ' Ammonium immobilization coefficient'                                  , emp_cell  , 1.0   , '   cu m. / (d. gN)'; ...
                    ' Nitrate immobilization coefficient'                                   , emp_cell  , 1.0   , '   cu m. / (d. gN)'; ...
                    ' Maximum ammonium immobilization coefficient'                          , emp_cell  , 0.1   , '   cu m. / (d. gN)'; ...
                    ' Maximum nitrate immobilization coefficient'                           , emp_cell  , 0.1   , '   cu m. / (d. gN)'; ...
                    ' Fraction of dissolved ammonium'                                       , emp_cell  , 0.05  , '          unitless'; ...
                    ' Fraction of dissolved nitrate'                                        , emp_cell  , 1.0   , '          unitless'; ...
                    ' Bioturbation diffusion coefficient at soil surface'                   , emp_cell  ,      4, '        sq cm / yr'; ...
                    ' Litter fragmentation'                                                 , emp_cell  ,0.0000215, '             / d'; ...
                    ' Fraction of nitrification lost as N2O flux - denitrification'         , emp_cell  , 0.02  , '          unitless'; ...
                    ' Carbon to nitrogen ratio of humus - 2 pools'                          , emp_cell  , 22    , '          unitless'; ...
                    ' Minimum fraction of the decompoased litter that undergoes humification - 2 pools' , emp_cell, 0.15  , '          unitless'; ...
                    ' Volatilization fraction of urea of applied fertilizer'                , emp_cell  , 0.205 , '          unitless'; ...
                    ' Volatilization fraction of ammonium of applied fertilizer'            , emp_cell  , 0.61  , '          unitless'; ...
                    ' Definition zone of disturbance - N uptake with root biomass'          , emp_cell  , 0.1   , '             mm   '; ...
                    ' Maximum root nitrate uptake - N uptake with root biomass'             , emp_cell  , 0.5   , '   mmolNO3 / g /hr'; ...
                    ' Michaelis nitrate constant - N uptake with root biomass'              , emp_cell  , 72    , '    mmolNO3 / cu m'; ...
                    ' Maximum root ammonium uptake - N uptake with root biomass'            , emp_cell  , 0.053 , '   mmolNH4 / g /hr'; ...
                    ' Michaelis ammonium constant - N uptake with root biomass'             , emp_cell  , 15    , '    mmolNh4 / cu m'; ...
                    };
                
                % Each species specific parameter
                para_soilCN_crop = {...
                    ' Constant ratio of ''natural litter input'' to ''belowground litter input'' ' , emp_cell   , 0.8 , '    unitless'; ...
                    };
                para_soilCN_crop_s = {...
                    ' Fraction of ammonium demand from total N demand'                      , emp_cell  , 0.0   , '          unitless'; ...
                    ' Fraction of nitrate demand from total N demand'                       , emp_cell  , 1.0   , '          unitless'; ...
                    ' Tortuosity factor in active N uptake'                                 , emp_cell  , 3.0   , '          unitless'; ...
                    ' Rescaled diffusion coefficient'                                       , emp_cell  , 0.06  , '          m / cu m'; ...
                    ' Nitrgeon fixation index (plant N from fixation / plant N from soil)'  , emp_cell  , 2.0   , '          unitless'; ...
                    ' Nitrgeon remobilization index (fraction of previous year''s N uptake)', emp_cell  , 0.6   , '          unitless'; ...
                    };
                
                %
                para_leaf_crop = {...
                    ' Latent Heat production from 1 or both sides of leaf'                  , emp_cell  , 1     , '          unitless';...
                    ' Sensible Heat production from 1 or both sides of leaf'                , emp_cell  , 1     , '          unitless';...
                    ' Longwave production from 1 or both sides of leaf'                     , emp_cell  , 2     , '          unitless';...
                    ' Leaf Type: 1-Broad leaf or 2-Needle leaf'                             , emp_cell  , 1     , '          unitless';...
                    };
                para_leaf_crop_s = {...
                    ' Maximum h2o storage capacity for foliage'                             , emp_cell  , 0.2     , '     mm / LAI unit';...
                    };
                
                %%
                para_canopy_crop = {...
                    ' Leaf width or needle diameter'                                        , emp_cell  , 0.05  , '              m   ';...
                    ' Shoot diameter for conifers or leaf width for broad leaf'             , emp_cell  , 0.05  , '              m   ';...
                    };
                
                para_canopy_crop_fixed = {...
                    ' Maximum wetted fraction'                                              , emp_cell  , 0.2   , '          unitless';...
                    ' Rainfall interception factor'                                         , emp_cell  , 0.2   , '          unitless';...
                    ' Canopy height'                                                        , emp_cell  , 40    , '              m   ';...
                    ' Flux tower observation height'                                        , emp_cell  , 64    , '              m   ';...
                    ' Canopy roughness length'                                              , emp_cell  , 5.2   , '              m   ';...
                    };
                
                %%
                para_photosynthesisC4_crop = {...
                    ' Maximum Rubisco capacity Vmax'                                        , emp_cell  , 45    , '   mol / (sq m. s)'; ...
                    ' Leaf respiration Rd'                                                  , emp_cell  , 0.8   , '   mol / (sq m. s)'; ...
                    ' Temperature sensitivity Q10'                                          , emp_cell  , 0.57  , '          unitless'; ...
                    ' Initial slope of CO2 response'                                        , emp_cell  , 0.7   , '   mol / (sq m. s)'; ...
                    ' Curvature factor theta'                                               , emp_cell  , 0.83  , '          unitless'; ...
                    ' Curvature factor beta'                                                , emp_cell  , 0.93  , '          unitless'; ...
                    ' Intrisic quantum yield of C4 photosynthesis - alpha'                  , emp_cell  , 0.04  , '          unitless'; ...
                    ' Vertical distribution of Photosynthetic capacity'                     , emp_cell  , 0.4   , '          unitless'; ...
                    };
                
                para_photosynthesisC3_crop = {...
                    ' Fraction absorbed Q available to photosystem II'                      , emp_cell  , 0.5   , '          unitless'; ...
                    ' Maximum rate of Rubisco-limited carbonxylation at 25 C - Vcmax'       , emp_cell  , 31    , '  umol / (sq m. s)'; ...
                    ' Maximum electron transport rate - Jmax'                               , emp_cell  , 37    , '  umol / (sq m. s)'; ...
                    ' Rd25'                                                                 , emp_cell  , 0.3   , '  umol / (sq m. s)'; ...
                    ' Vertical distribution of Photosynthetic capacity'                     , emp_cell  , 0.4   , '          unitless'; ...
                    };
                
                %%
                para_conductance_crop = {...
                    ' Slope parameter in Ball-Berry model, m'                               , emp_cell  , 12    , '          unitless'; ...
                    ' Intercepts in Ball Berry model, b'                                    , emp_cell  , 0.008 , '   mol / (sq m. s)'; ...
                    ' Sensitivity parameter for initial decrease in leaf potential'         , emp_cell  , 3.2   , '         unitless '; ...
                    ' Leaf potential at which half of the hydraulic conductance is lost'    , emp_cell  , -3    , '              MPa '; ...
                    ' Radial conductivity of the root system'                               , emp_cell  ,4.66e-9, '               /s '; ...
                    ' Axial specific conductivity of the root system'                       , emp_cell  ,4.5e-5 , '             mm/s '; ...
                    ' Parameter, kpar_ax'                                                   , emp_cell  ,0.00036, '          unitless'; ...
                    ' Parameter, Rp'                                                        , emp_cell  , 8     , '          unitless'; ...
                    };
                
                
                para_N_allocation = {...
                    ' Fraction of leaf structural protein'                               , emp_cell  , 0.31    , 'unitless'; ...
                    ' Fraction of thylakoid nitrogen that is allocated to light harvesting pool',...
                    emp_cell  , 0.80 , '   unitless)'; ...
                    ' Nitrogen to chlorophyll ratio of pigment protein complexes'         , emp_cell  , 38.5   , 'mol N (mol Chl)-1 '; ...
                    ' Min Light extinction coefficient'                                         , emp_cell  , 0.5    , 'unitless '; ...
                    ' Max Light extinction coefficient'                                         , emp_cell  , 1.8    , 'unitless '; ...
                    ' Min Fraction of leaf nitrogen allocated into thylakoid pool'              , emp_cell  , 0.20  , 'unitless '; ...
                    ' Max Fraction of leaf nitrogen allocated into thylakoid pool'              , emp_cell  , 0.40  , 'unitless '; ...
                    ' Min Leaf nitrogen allocated to rubisco pool'                              , emp_cell  , 0.06 , 'unitless';...
                    ' Max Leaf nitrogen allocated to rubisco pool'                              , emp_cell  , 0.20 , 'unitless';...
                    };
                
                para_CGM1 = {...
                    ' Base temperature for GDD accumulation', emp_cell, 10, '`C'; ...
                    ' Cut-off temperature for GDD accumulation', emp_cell, 30, '`C'; ...
                    ' GDD from seeding to emergence', emp_cell, 47, 'Degree Days'; ...
                    ' GDD from seeding to initial vegetative end', emp_cell, 591, 'Degree Days'; ...
                    ' GDD from seeding to normal vegetative end', emp_cell, 732, 'Degree Days'; ...
                    ' GDD from seeding to initial reproductive end', emp_cell, 904, 'Degree Days'; ...
                    ' GDD from seeding to normal reproductive end', emp_cell, 1482, 'Degree Days'; ...
                    ' Q10 for maintenance respiration', emp_cell, 2.0, 'unitless'; ...
                    ' Specific leaf area', emp_cell, 0.015, 'unitless'; ...
                    'Leaf maintenance respiration at 25`C', emp_cell, 1.0, 'μmol CO2/m2/s'; ...
                    'Stem maintenance respiration at 25`C', emp_cell, 0.05, 'μmol CO2/m2/s'; ...
                    'Root maintenance respiration at 25`C', emp_cell, 0.05, 'μmol CO2/m2/s'; ...
                    'Grain maintenance respiration at 25`C', emp_cell, 0.01, 'μmol CO2/m2/s'; ...
                    'Fraction of growth respiration', emp_cell, 0.20, 'unitless'; ...
                    'Leaf turnover coefficient at initial reproductive', emp_cell, 0.20, '1/s'; ...
                    'Leaf turnover coefficient at normal reproductive', emp_cell, 0.30, '1/s'; ...
                    'Stem turnover coefficient at initial reproductive', emp_cell, 0.12, '1/s'; ...
                    'Stem turnover coefficient at normal reproductive', emp_cell, 0.06, '1/s'; ...
                    'Root turnover coefficient at initial reproductive', emp_cell, 0.12, '1/s'; ...
                    'Root turnover coefficient at normal reproductive', emp_cell, 0.06, '1/s'; ...
                    'Leaf carbon fraction', emp_cell, 0.416, 'unitless'; ...
                    'Stem carbon fraction', emp_cell, 0.458, 'unitless'; ...
                    'Root carbon fraction', emp_cell, 0.343, 'unitless'; ...
                    'Grain carbon fraction', emp_cell, 0.400, 'unitless'; ...
                    'Harvest Index', emp_cell, 0.65, 'unitless'; ...
                    'Foliage nitrogen concentration', emp_cell, 1.5, '%';...
                    'Characteristic T for leaf freezing', emp_cell, 268, 'K';...
                    'Coefficient for leaf temperature stress death at initial reproductive', emp_cell, 0.05, '1/s';...
                    'Coefficient for leaf temperature stress death at normal reproductive', emp_cell, 0.05, '1/s';...
                    
                    };
                
                
                para_CGMF = {...
                    'Fraction of carbohydrate flux to LEAF at initial vegetative', emp_cell, 0.50, 'unitless'; ...
                    'Fraction of carbohydrate flux to LEAF at normal vegetative', emp_cell, 0.32, 'unitless'; ...
                    'Fraction of carbohydrate flux to LEAF at initial productive', emp_cell, 0.18, 'unitless'; ...
                    'Fraction of carbohydrate flux to LEAF at normal productive', emp_cell, 0.08, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at initial vegetative', emp_cell, 0.25, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at normal vegetative', emp_cell, 0.38, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at initial productive', emp_cell, 0.25, 'unitless'; ...
                    'Fraction of carbohydrate flux to STEM at normal productive', emp_cell, 0.17, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at initial vegetative', emp_cell, 0.25, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at normal vegetative', emp_cell, 0.30, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at initial productive', emp_cell, 0.17, 'unitless'; ...
                    'Fraction of carbohydrate flux to ROOT at normal productive', emp_cell, 0.10, 'unitless'; ...
                    'Fraction of carbohydrate flux to GRAIN at initial productive', emp_cell, 0.40, 'unitless'; ...
                    'Fraction of carbohydrate flux to GRAIN at normal productive', emp_cell, 0.65, 'unitless'; ...
                    };
                
                current_species = 1;
                
            else
                
                load (fullpath_P, ...
                    ...%Parameters
                    'para_leaf_crop', 'para_leaf_crop_s',...
                    'para_canopy_crop', 'para_canopy_crop_fixed', ...
                    ... % MicroEnvironment
                    'para_microenvironment', 'para_radiation', 'para_soil',...
                    ... % Ecophysiology
                    'para_photosynthesisC3_crop', 'para_photosynthesisC4_crop', ...
                    'para_respiration', 'para_conductance_crop', ...
                    ... % CropGrowth
                    'para_CGM1', 'para_CGMF',...
                    ... % Nutrient
                    'para_soilCN', 'para_soilCN_crop', 'para_soilCN_crop_s',...
                    ... % N-Allocation
                    'para_N_allocation',...
                    ... %Others
                    'current_species')
                
                app.SavedLamp.Color='green';
                
            end
                        
            load (  './Temps/temp_model.mat',...
                'CGM', 'Soil_nutrient', 'NCanAlMod')
            
            app.LeafTable.Data=para_leaf_crop;
            app.LeafTable_2.Data=para_leaf_crop_s;
            app.CanopyTable.Data=para_canopy_crop;
            app.CanopyTable_2.Data=para_canopy_crop_fixed;
            
            app.MicroenvironTable.Data=para_microenvironment;
            app.RadiationTable.Data=para_radiation;
            app.SoilTable.Data=para_soil;
            
            app.C3PhotoTable.Data=para_photosynthesisC3_crop;
            app.C4PhotoTable.Data=para_photosynthesisC4_crop;
            app.RespirationTable.Data=para_respiration;
            app.ConductanceTable.Data=para_conductance_crop;
            
            app.NutrientSiteTable.Data=para_soilCN;
            app.NutrientSpeciesTable.Data=para_soilCN_crop;
            app.NutrientSpeciesTable_2.Data=para_soilCN_crop_s;
            
            app.NallocationTable.Data=para_N_allocation;
            
            app.CGMFTable.Data=para_CGMF;
            app.CGM1Table.Data=para_CGM1;
            
            
            if Soil_nutrient==1
                app.NUTRIENTTab.Parent=app.PROCESSES;
            else
                app.NUTRIENTTab.Parent=[];
            end
            
            if NCanAlMod==1
                app.NALLOCATIONTab.Parent=app.PROCESSES;
            else
                app.NALLOCATIONTab.Parent=[];
            end
            
            if CGM==1
                app.CROPGROWTHTab.Parent=app.PROCESSES;
            else
                app.CROPGROWTHTab.Parent=[];
            end
            
        end

        % Button pushed function: DecomAddEditButton
        function DecomAddEditButtonPushed(app, event)
            
            run('model_parameters_decom_M.m')
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            ii=app.SpeciesDropDown.Value;
            
            filename_P= ['ParSpec_', ii, '.mat'];
            
            load('.\Temps\temporary.mat', 'config_path');
            
            fullpath_P= fullfile(config_path, 'Par', filename_P);
            
            load('./Temps/temp_model.mat', 'ParFilesList')

            current_species=1;
            
            para_leaf_crop=app.LeafTable.Data;
            para_leaf_crop_s=app.LeafTable_2.Data;
            para_canopy_crop=app.CanopyTable.Data;
            para_canopy_crop_fixed=app.CanopyTable_2.Data;
            
            for cc=1:size(para_leaf_crop,1)
                if ischar(para_leaf_crop{cc,2})
                    para_leaf_crop{cc,2}=str2double(para_leaf_crop{cc,2});
                end
            end
            for cc=1:size(para_leaf_crop_s,1)
                if ischar(para_leaf_crop_s{cc,2})
                    para_leaf_crop_s{cc,2}=str2double(para_leaf_crop_s{cc,2});
                end
            end
            for cc=1:size(para_canopy_crop,1)
                if ischar(para_canopy_crop{cc,2})
                    para_canopy_crop{cc,2}=str2double(para_canopy_crop{cc,2});
                end
            end
            for cc=1:size(para_canopy_crop_fixed,1)
                if ischar(para_canopy_crop_fixed{cc,2})
                    para_canopy_crop_fixed{cc,2}=str2double(para_canopy_crop_fixed{cc,2});
                end
            end
            
            para_microenvironment=app.MicroenvironTable.Data;
            para_radiation=app.RadiationTable.Data;
            para_soil=app.SoilTable.Data;
            
            for cc=1:size(para_microenvironment,1)
                if ischar(para_microenvironment{cc,2})
                    para_microenvironment{cc,2}=str2double(para_microenvironment{cc,2});
                end
            end
            for cc=1:size(para_radiation,1)
                if ischar(para_radiation{cc,2})
                    para_radiation{cc,2}=str2double(para_radiation{cc,2});
                end
            end
            for cc=1:size(para_soil,1)
                if ischar(para_soil{cc,2})
                    para_soil{cc,2}=str2double(para_soil{cc,2});
                end
            end
            
            para_photosynthesisC3_crop=app.C3PhotoTable.Data;
            para_photosynthesisC4_crop=app.C4PhotoTable.Data;
            para_respiration=app.RespirationTable.Data;
            para_conductance_crop=app.ConductanceTable.Data;
            
            for cc=1:size(para_photosynthesisC3_crop,1)
                if ischar(para_photosynthesisC3_crop{cc,2})
                    para_photosynthesisC3_crop{cc,2}=str2double(para_photosynthesisC3_crop{cc,2});
                end
            end
            for cc=1:size(para_photosynthesisC4_crop,1)
                if ischar(para_photosynthesisC4_crop{cc,2})
                    para_photosynthesisC4_crop{cc,2}=str2double(para_photosynthesisC4_crop{cc,2});
                end
            end
            for cc=1:size(para_respiration,1)
                if ischar(para_respiration{cc,2})
                    para_respiration{cc,2}=str2double(para_respiration{cc,2});
                end
            end
            for cc=1:size(para_conductance_crop,1)
                if ischar(para_conductance_crop{cc,2})
                    para_conductance_crop{cc,2}=str2double(para_conductance_crop{cc,2});
                end
            end
            
            para_soilCN=app.NutrientSiteTable.Data;
            para_soilCN_crop=app.NutrientSpeciesTable.Data;
            para_soilCN_crop_s=app.NutrientSpeciesTable_2.Data;
            
            for cc=1:size(para_soilCN,1)
                if ischar(para_soilCN{cc,2})
                    para_soilCN{cc,2}=str2double(para_soilCN{cc,2});
                end
            end
            for cc=1:size(para_soilCN_crop,1)
                if ischar(para_soilCN_crop{cc,2})
                    para_soilCN_crop{cc,2}=str2double(para_soilCN_crop{cc,2});
                end
            end
            for cc=1:size(para_soilCN_crop_s,1)
                if ischar(para_soilCN_crop_s{cc,2})
                    para_soilCN_crop_s{cc,2}=str2double(para_soilCN_crop_s{cc,2});
                end
            end
            
            para_N_allocation=app.NallocationTable.Data;
            
            for cc=1:size(para_N_allocation,1)
                if ischar(para_N_allocation{cc,2})
                    para_N_allocation{cc,2}=str2double(para_N_allocation{cc,2});
                end
            end
            
            para_CGMF=app.CGMFTable.Data;
            para_CGM1=app.CGM1Table.Data;
            
            for cc=1:size(para_CGMF,1)
                if ischar(para_CGMF{cc,2})
                    para_CGMF{cc,2}=str2double(para_CGMF{cc,2});
                end
            end
            for cc=1:size(para_CGM1,1)
                if ischar(para_CGM1{cc,2})
                    para_CGM1{cc,2}=str2double(para_CGM1{cc,2});
                end
            end
            
            
            save (fullpath_P, ...
                ...%Parameters
                'para_leaf_crop', 'para_leaf_crop_s',...
                'para_canopy_crop', 'para_canopy_crop_fixed', ...
                ... % MicroEnvironment
                'para_microenvironment', 'para_radiation', 'para_soil',...
                ... % Ecophysiology
                'para_photosynthesisC3_crop', 'para_photosynthesisC4_crop', ...
                'para_respiration', 'para_conductance_crop', ...
                ... % CropGrowth
                'para_CGM1', 'para_CGMF',...
                ... % Nutrient
                'para_soilCN', 'para_soilCN_crop', 'para_soilCN_crop_s',...
                ... % N-Allocation
                'para_N_allocation',...
                ... %Others
                'current_species')
            
            app.SavedLamp.Color='green';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'parameter.png';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Multi-Layer Canopy Soil Model';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'PARAMETERS';
            app.SpeciesDropDownLabel = uilabel(app.UIFigure);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.UIFigure);
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.PROCESSES = uitabgroup(app.UIFigure);
            app.LEAFCANOPYTab = uitab(app.PROCESSES);
            app.LEAFCANOPYTab.Title = 'LEAF & CANOPY';
            app.LeafTable = uitable(app.LEAFCANOPYTab);
            app.LeafTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.LeafTable.RowName = {};
            app.LeafTable.ColumnEditable = [false true false false];
            app.LeafTable_2 = uitable(app.LEAFCANOPYTab);
            app.LeafTable_2.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.LeafTable_2.RowName = {};
            app.LeafTable_2.ColumnEditable = [false true false false];
            app.LeafLabel = uilabel(app.LEAFCANOPYTab);
            app.LeafLabel.Text = 'Leaf';
            app.CanopyLabel = uilabel(app.LEAFCANOPYTab);
            app.CanopyLabel.Text = 'Canopy';
            app.CanopyTable = uitable(app.LEAFCANOPYTab);
            app.CanopyTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.CanopyTable.RowName = {};
            app.CanopyTable.ColumnEditable = [false true false false];
            app.CanopyTable_2 = uitable(app.LEAFCANOPYTab);
            app.CanopyTable_2.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.CanopyTable_2.RowName = {};
            app.CanopyTable_2.ColumnEditable = [false true false false];
            app.MICROENVIRONMENTTab = uitab(app.PROCESSES);
            app.MICROENVIRONMENTTab.Title = 'MICROENVIRONMENT';
            app.MicroenvironTable = uitable(app.MICROENVIRONMENTTab);
            app.MicroenvironTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.MicroenvironTable.RowName = {};
            app.MicroenvironTable.ColumnEditable = [false true false false];
            app.MicroenvironmentLabel = uilabel(app.MICROENVIRONMENTTab);
            app.MicroenvironmentLabel.Text = 'Microenvironment';
            app.RadiationTable = uitable(app.MICROENVIRONMENTTab);
            app.RadiationTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.RadiationTable.RowName = {};
            app.RadiationTable.ColumnEditable = [false true false false];
            app.RadiationLabel = uilabel(app.MICROENVIRONMENTTab);
            app.RadiationLabel.Text = 'Radiation';
            app.SoilTable = uitable(app.MICROENVIRONMENTTab);
            app.SoilTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.SoilTable.RowName = {};
            app.SoilTable.ColumnEditable = [false true false false];
            app.SoilLabel = uilabel(app.MICROENVIRONMENTTab);
            app.SoilLabel.Text = 'Soil';
            app.ECOPHYSIOLOGYTab = uitab(app.PROCESSES);
            app.ECOPHYSIOLOGYTab.Title = 'ECOPHYSIOLOGY';
            app.ECOsubTab = uitabgroup(app.ECOPHYSIOLOGYTab);
            app.PHOTOSYNTHESISRESPIRATIONTab = uitab(app.ECOsubTab);
            app.PHOTOSYNTHESISRESPIRATIONTab.Title = 'PHOTOSYNTHESIS & RESPIRATION';
            app.RespirationTable = uitable(app.PHOTOSYNTHESISRESPIRATIONTab);
            app.RespirationTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.RespirationTable.RowName = {};
            app.RespirationTable.ColumnEditable = [false true false false];
            app.RespirationLabel = uilabel(app.PHOTOSYNTHESISRESPIRATIONTab);
            app.RespirationLabel.Text = 'Respiration';
            app.C3PhotoTable = uitable(app.PHOTOSYNTHESISRESPIRATIONTab);
            app.C3PhotoTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.C3PhotoTable.RowName = {};
            app.C3PhotoTable.ColumnEditable = [false true false false];
            app.C3typeLabel = uilabel(app.PHOTOSYNTHESISRESPIRATIONTab);
            app.C3typeLabel.Text = 'C3 type';
            app.C4PhotoTable = uitable(app.PHOTOSYNTHESISRESPIRATIONTab);
            app.C4PhotoTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.C4PhotoTable.RowName = {};
            app.C4PhotoTable.ColumnEditable = [false true false false];
            app.C4typeLabel = uilabel(app.PHOTOSYNTHESISRESPIRATIONTab);
            app.C4typeLabel.Text = 'C4 type';
            app.CONDUCTANCETab = uitab(app.ECOsubTab);
            app.CONDUCTANCETab.Title = 'CONDUCTANCE';
            app.ConductanceTable = uitable(app.CONDUCTANCETab);
            app.ConductanceTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.ConductanceTable.RowName = {};
            app.ConductanceTable.ColumnEditable = [false true false false];
            app.ConductanceLabel = uilabel(app.CONDUCTANCETab);
            app.ConductanceLabel.Text = 'Conductance';
            app.CROPGROWTHTab = uitab(app.PROCESSES);
            app.CROPGROWTHTab.Title = 'CROP GROWTH';
            app.CGMFTable = uitable(app.CROPGROWTHTab);
            app.CGMFTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.CGMFTable.RowName = {};
            app.CGMFTable.ColumnEditable = [false true false false];
            app.CarbohydratetoLeafStemRootandGrainLabel = uilabel(app.CROPGROWTHTab);
            app.CarbohydratetoLeafStemRootandGrainLabel.Text = 'Carbohydrate to Leaf, Stem, Root, and Grain';
            app.CGM1Table = uitable(app.CROPGROWTHTab);
            app.CGM1Table.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.CGM1Table.RowName = {};
            app.CGM1Table.ColumnEditable = [false true false false];
            app.CropgrowthparametersLabel = uilabel(app.CROPGROWTHTab);
            app.CropgrowthparametersLabel.Text = 'Crop growth parameters';
            app.NUTRIENTTab = uitab(app.PROCESSES);
            app.NUTRIENTTab.Title = 'NUTRIENT';
            app.NutrientSiteTable = uitable(app.NUTRIENTTab);
            app.NutrientSiteTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.NutrientSiteTable.RowName = {};
            app.NutrientSiteTable.ColumnEditable = [false true false false];
            app.NutrientSiteSpecificLabel = uilabel(app.NUTRIENTTab);
            app.NutrientSiteSpecificLabel.Text = 'Nutrient: Site Specific';
            app.NutrientSpeciesTable = uitable(app.NUTRIENTTab);
            app.NutrientSpeciesTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.NutrientSpeciesTable.RowName = {};
            app.NutrientSpeciesTable.ColumnEditable = [false true false false];
            app.NutrientSpeciesSpecificLabel = uilabel(app.NUTRIENTTab);
            app.NutrientSpeciesSpecificLabel.Text = 'Nutrient: Species Specific';
            app.NutrientSpeciesTable_2 = uitable(app.NUTRIENTTab);
            app.NutrientSpeciesTable_2.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.NutrientSpeciesTable_2.RowName = {};
            app.NutrientSpeciesTable_2.ColumnEditable = [false true false false];
            app.InputdecompositionparametersLabel = uilabel(app.NUTRIENTTab);
            app.InputdecompositionparametersLabel.Text = 'Input decomposition parameters';
            app.DecomAddEditButton = uibutton(app.NUTRIENTTab, 'push');
            app.DecomAddEditButton.ButtonPushedFcn = createCallbackFcn(app, @DecomAddEditButtonPushed, true);
            app.DecomAddEditButton.Text = 'Add / Edit';
            app.NALLOCATIONTab = uitab(app.PROCESSES);
            app.NALLOCATIONTab.Title = 'N-ALLOCATION';
            app.NallocationTable = uitable(app.NALLOCATIONTab);
            app.NallocationTable.ColumnName = {'Name'; 'Value'; 'Default'; 'Unit'};
            app.NallocationTable.RowName = {};
            app.NallocationTable.ColumnEditable = [false true false false];
            app.NallocationparametersLabel = uilabel(app.NALLOCATIONTab);
            app.NallocationparametersLabel.Text = 'N-allocation parameters';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavedLamp = uilamp(app.UIFigure);
            app.SavedLamp.Tooltip = {'Indicate if the file is saved.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_parameters_App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end