classdef model_setup_App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        SpecifySiteInformationPanel     matlab.ui.container.Panel
        LatitudedecimalEditFieldLabel   matlab.ui.control.Label
        LatitudedecimalEditField        matlab.ui.control.NumericEditField
        LongitudedecimalEditFieldLabel  matlab.ui.control.Label
        LongitudedecimalEditField       matlab.ui.control.NumericEditField
        ElevationmaboveMSLEditFieldLabel  matlab.ui.control.Label
        ElevationmaboveMSLEditField     matlab.ui.control.NumericEditField
        SpecifiedDOYEditFieldLabel      matlab.ui.control.Label
        SpecifiedDOYEditField           matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        LoadMapButton                   matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            load ('./Temps/temp_model.mat', "lat_face", "long_face",...
                "elev_face", "DOY_start", "DOY_end");
            
            app.LatitudedecimalEditField.Value=lat_face;
            app.LongitudedecimalEditField.Value=long_face;
            app.ElevationmaboveMSLEditField.Value=elev_face;
            app.SpecifiedDOYEditField.Value=DOY_start;
            
            if DOY_end==365
                app.toEditField.Value=366;
            else
                app.toEditField.Value=DOY_end;
            end
            
            pm = uipanel(app.SpecifySiteInformationPanel,'Position',[0,0,320,207]);
            
            lat_data=app.LatitudedecimalEditField.Value;
            lon_data=app.LongitudedecimalEditField.Value;
            gx = geoaxes(pm);
            geoscatter(gx,lat_data,lon_data, 100, 'red', '^', 'filled')
            
            geobasemap(gx,'landcover')
            
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            
            pm = uipanel(app.SpecifySiteInformationPanel,'Position',[0,0,320,207]);
            
            lat_data=app.LatitudedecimalEditField.Value;
            
            lon_data=app.LongitudedecimalEditField.Value;
            
            gx = geoaxes(pm);
            
            geoscatter(gx,lat_data,lon_data, 100, 'red', '^', 'filled')
            
            geobasemap(gx,'landcover')
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            lat_face=app.LatitudedecimalEditField.Value;
            long_face=app.LongitudedecimalEditField.Value;
            elev_face=app.ElevationmaboveMSLEditField.Value;
            
            DOY_start=app.SpecifiedDOYEditField.Value;
%             DOY_end=app.toEditField.Value;
            
            if app.toEditField.Value==365
                DOY_end=366;
            else
                DOY_end=app.toEditField.Value;
            end
            
            save ('./Temps/temp_model.mat', "lat_face", "long_face",...
                "elev_face", "DOY_start", "DOY_end", '-append');
            
            closereq
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'wheat.png';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Multi-Layer Canopy Soil Model';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Setup';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'GUI_Fig.jpg';
            app.SpecifySiteInformationPanel = uipanel(app.UIFigure);
            app.SpecifySiteInformationPanel.Title = 'Specify Site Information';
            app.LatitudedecimalEditFieldLabel = uilabel(app.SpecifySiteInformationPanel);
            app.LatitudedecimalEditFieldLabel.Text = 'Latitude (decimal)';
            app.LatitudedecimalEditField = uieditfield(app.SpecifySiteInformationPanel, 'numeric');
            app.LongitudedecimalEditFieldLabel = uilabel(app.SpecifySiteInformationPanel);
            app.LongitudedecimalEditFieldLabel.Text = 'Longitude (decimal)';
            app.LongitudedecimalEditField = uieditfield(app.SpecifySiteInformationPanel, 'numeric');
            app.ElevationmaboveMSLEditFieldLabel = uilabel(app.SpecifySiteInformationPanel);
            app.ElevationmaboveMSLEditFieldLabel.Text = 'Elevation (m) above MSL';
            app.ElevationmaboveMSLEditField = uieditfield(app.SpecifySiteInformationPanel, 'numeric');
            app.SpecifiedDOYEditFieldLabel = uilabel(app.SpecifySiteInformationPanel);
            app.SpecifiedDOYEditFieldLabel.Text = 'Specified DOY';
            app.SpecifiedDOYEditField = uieditfield(app.SpecifySiteInformationPanel, 'numeric');
            app.SpecifiedDOYEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.SpecifySiteInformationPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.SpecifySiteInformationPanel, 'numeric');
            app.toEditField.Value = 365;
            app.LoadMapButton = uibutton(app.SpecifySiteInformationPanel, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = 'Load Map';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_setup_App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end